classdef MultiPhotometry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultiPhotometryUIFigure         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DeltaFFZscoreCalculationTab     matlab.ui.container.Tab
        cue_editEditFieldLabel          matlab.ui.control.Label
        cue_editEditField               matlab.ui.control.EditField
        LoadCueButton                   matlab.ui.control.Button
        cal_editEditFieldLabel          matlab.ui.control.Label
        cal_editEditField               matlab.ui.control.EditField
        LoadCalButton                   matlab.ui.control.Button
        DrawallButton                   matlab.ui.control.Button
        ParameterSetPanel               matlab.ui.container.Panel
        RateDropDownLabel               matlab.ui.control.Label
        RateDropDown                    matlab.ui.control.DropDown
        CueDropDownLabel                matlab.ui.control.Label
        CueDropDown                     matlab.ui.control.DropDown
        EventtimeEditFieldLabel         matlab.ui.control.Label
        EventtimeEditField              matlab.ui.control.EditField
        EventTimeCheckBox               matlab.ui.control.CheckBox
        CalDropDownLabel                matlab.ui.control.Label
        CalDropDown                     matlab.ui.control.DropDown
        OffsetEditFieldLabel            matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.EditField
        CalCorrectionPanel              matlab.ui.container.Panel
        LambdaDropDownLabel             matlab.ui.control.Label
        LambdaDropDown                  matlab.ui.control.DropDown
        DrawCalButton                   matlab.ui.control.Button
        ReplaceButton                   matlab.ui.control.Button
        CorrecttimesEditField_3Label    matlab.ui.control.Label
        CorrecttimesEditField           matlab.ui.control.NumericEditField
        CorrecttimesEditField_4Label    matlab.ui.control.Label
        CorrecttimesEditField_2         matlab.ui.control.NumericEditField
        AveragePanel                    matlab.ui.container.Panel
        AnalyNumEditFieldLabel          matlab.ui.control.Label
        AnalyNumEditField               matlab.ui.control.NumericEditField
        TrailFromEditFieldLabel         matlab.ui.control.Label
        TrailFromEditField              matlab.ui.control.NumericEditField
        PreTimeEditFieldLabel           matlab.ui.control.Label
        PreTimeEditField                matlab.ui.control.NumericEditField
        PostTimeEditFieldLabel          matlab.ui.control.Label
        PostTimeEditField               matlab.ui.control.NumericEditField
        ControlTimeEditFieldLabel       matlab.ui.control.Label
        ControlTimeEditField            matlab.ui.control.NumericEditField
        ControlTimeEditField_2Label     matlab.ui.control.Label
        ControlTimeEditField_2          matlab.ui.control.NumericEditField
        ClimsEditFieldLabel             matlab.ui.control.Label
        ClimsEditField                  matlab.ui.control.NumericEditField
        ClimsEditField_2Label           matlab.ui.control.Label
        ClimsEditField_2                matlab.ui.control.NumericEditField
        AverageButton                   matlab.ui.control.Button
        zscoreCheckBox                  matlab.ui.control.CheckBox
        DeltaF_FCheckBox                matlab.ui.control.CheckBox
        PlayBackButton                  matlab.ui.control.Button
        TrailFromEditField_2            matlab.ui.control.NumericEditField
        PlotPanel                       matlab.ui.container.Panel
        ControlTimeEditField_3Label     matlab.ui.control.Label
        ControlTimeEditField_3          matlab.ui.control.NumericEditField
        ControlTimeEditField_4Label     matlab.ui.control.Label
        ControlTimeEditField_4          matlab.ui.control.NumericEditField
        EvaluationtimeEditFieldLabel    matlab.ui.control.Label
        EvaluationtimeEditField         matlab.ui.control.NumericEditField
        EvaluationtimeEditField_2Label  matlab.ui.control.Label
        EvaluationtimeEditField_2       matlab.ui.control.NumericEditField
        PlotButton                      matlab.ui.control.Button
        PlayBackButton_2                matlab.ui.control.Button
        TomatcsvButton                  matlab.ui.control.Button
        DeltaffTreatTab                 matlab.ui.container.Tab
        EventFrequencyPanel             matlab.ui.container.Panel
        StartTimeEditFieldLabel         matlab.ui.control.Label
        StartTimeEditField              matlab.ui.control.NumericEditField
        EndTimeEditFieldLabel           matlab.ui.control.Label
        EndTimeEditField                matlab.ui.control.NumericEditField
        BaselineStartTimeEditFieldLabel  matlab.ui.control.Label
        BaselineStartTimeEditField      matlab.ui.control.NumericEditField
        BaselineEndTimeEditFieldLabel   matlab.ui.control.Label
        BaselineEndTimeEditField        matlab.ui.control.NumericEditField
        MultipleEditFieldLabel          matlab.ui.control.Label
        MultipleEditField               matlab.ui.control.NumericEditField
        DrawCalButton_2                 matlab.ui.control.Button
        EventFPSButton                  matlab.ui.control.Button
        EvaluationStartsLabel           matlab.ui.control.Label
        EvaluationEndsLabel             matlab.ui.control.Label
        ControlStarttimesLabel          matlab.ui.control.Label
        ControlEndtimesLabel            matlab.ui.control.Label
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        AUCPeakValleyPanel              matlab.ui.container.Panel
        AUC_editEditFieldLabel          matlab.ui.control.Label
        AUC_editEditField               matlab.ui.control.EditField
        LoadTrailButton                 matlab.ui.control.Button
        DrawTrailButton                 matlab.ui.control.Button
        AUCStartTimeEditFieldLabel      matlab.ui.control.Label
        AUCStartTimeEditField           matlab.ui.control.NumericEditField
        PeakStartTimeEditFieldLabel     matlab.ui.control.Label
        PeakStartTimeEditField          matlab.ui.control.NumericEditField
        AUCEndTimeEditFieldLabel        matlab.ui.control.Label
        AUCEndTimeEditField             matlab.ui.control.NumericEditField
        PeakEndTimeEditFieldLabel       matlab.ui.control.Label
        PeakEndTimeEditField            matlab.ui.control.NumericEditField
        AUCButton                       matlab.ui.control.Button
        PeakValleyButton                matlab.ui.control.Button
        AveragemicePanel                matlab.ui.container.Panel
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        LoadTrailsButton                matlab.ui.control.Button
        AverageButton_2                 matlab.ui.control.Button
        RGB1EditFieldLabel              matlab.ui.control.Label
        RGB1EditField                   matlab.ui.control.EditField
        RGB2EditFieldLabel              matlab.ui.control.Label
        RGB2EditField                   matlab.ui.control.EditField
        RGB3EditFieldLabel              matlab.ui.control.Label
        RGB3EditField                   matlab.ui.control.EditField
        RGB4EditFieldLabel              matlab.ui.control.Label
        RGB4EditField                   matlab.ui.control.EditField
        RGB5EditFieldLabel              matlab.ui.control.Label
        RGB5EditField                   matlab.ui.control.EditField
        RGB6EditFieldLabel              matlab.ui.control.Label
        RGB6EditField                   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        file_path_and_name_cue % Description
        file_part_cue
        channelnum_cue
        length_cue
        data_cue
        current_cue % Description
        upindex
        trialnum
        cue_read=0;
        file_path_and_name_cal
        cal_read=0;
        file_part_cal
        channelnum_cal
        length_cal
        interval_cal
        cal
        current_cal
        current_cal_num
        correction_data
        index_corr=[];
        psth_sem_all=[];
        psth_ALL=[];
        psth_mean=[];
        psth_times=[];
        psth_sem=[];
        file_path_and_name_trail % Description
        trail_data=[]; % Description
        file_N % Description
        cal_0=[]; % Description
        cal_raw % Description
        psth1=[] % Description
        FilePath=[]; % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('TDMSReader');
            addpath('Libraries');
            addpath(genpath(pwd));
        end

        % Button pushed function: LoadCueButton
        function LoadCueButtonPushed(app, event)
            clc
            [filename, pathname] = uigetfile({'*.tdms';'*.mat';'*.CSV'}, 'Open cue_data file');
            app.file_path_and_name_cue = fullfile(pathname, filename);
            
            [~,~,filepart_cue]=fileparts(app.file_path_and_name_cue);
            % [filename,pathname]=uigetfile({'*.tdms'},'Open cue_data file','MultiSelect','on');%读取多个mat文件
            % handles.file_path_and_name_cue = fullfile(pathname, filename(i));
            if strcmp(filepart_cue,'.csv')
                cue=csvread(app.file_path_and_name_cue);
            elseif strcmp(filepart_cue,'.tdms')
                file = TDMS_readTDMSFile(app.file_path_and_name_cue);
                cue=cat(1,file.data{3:end})';
            elseif strcmp(filepart_cue,'.mat')
                cue=load(app.file_path_and_name_cue);
                if isfield(cue,'data')
                    cue=cue.data;
                else
                    msgbox('Please select the correct converted matfile !!!!!') ;
                end
            end
            app.file_part_cue=filepart_cue;
            if isequal(filename,0)
                return;
            end
            ncue=zeros(1,4);
            %             handles.filenumber=1;
            %         string=char(handles.file_path_and_name_cue(1));
            % file = TDMS_readTDMSFile(handles.file_path_and_name_cue);
            % cue=cat(1,file.data{3:end})';
            app.channelnum_cue=size(cue,2);
            % record_duration=find(cue(:,1));
            % handles.length_cue=length(record_duration);
            % cue=cue(record_duration,:);
            ncue=[ncue;cue];
            ncue=ncue(2:end,:);
            app.length_cue=size(ncue,1);
            if isfield(app,'length_cal')
                mod_cue_cal=mod(app.length_cue,handles.length_cal);
                if mod_cue_cal~=0
                    msgbox('Operation failed.Please make sure the input cue/cal is correct!!!');
                    app.cue_read=0;
                    %                     guidata(hObject, handles);
                    return
                end
            end
            
            app.channelnum_cue=size(ncue,2);
            app.cue_editEditField.Value=app.file_path_and_name_cue;
            
            %             set(handles.cue_edit, 'String', pathname);
            cue_index=cell(app.channelnum_cue,1);
            for i=1:app.channelnum_cue
                cue_index{i}=num2str(i);
            end
            app.CueDropDown.Items=cue_index;
            app.CueDropDown.Value='1';
            %             set(handles.pm_cue,'String',num2str((1:1:handles.channelnum_cue)'));
            %             set(handles.pm_cue,'Value',1);
            
            %             for i=1:handles.channelnum_cue
            %                 eval(['handles.cue',num2str(i),'=','ncue(:,i)',';']);
            %             end
            app.data_cue=ncue;
            %%%%%%修复下降沿
            cue=smooth(ncue(:,1),10);
            cue(cue<1)=0;
            cue(cue>=1)=1;
            app.current_cue=cue;
            app.upindex=1+find(diff(app.current_cue)==1)'-4;
            % upindex=handles.upindex;
            %%%%%%%%%%%%%%%%%%%修复过近的trail存在
            interval=0.001;
            app.trialnum = size(app.upindex,2);
            %             app.trialnum=app.trialnum(2);
            if app.trialnum>1
                for i=1:app.trialnum-1
                    for j=(i+1):app.trialnum
                        if (app.upindex(j)-app.upindex(i))*interval<1
                            app.upindex(j)=0;
                        end
                    end
                end
                app.upindex(app.upindex==0)=[];
            end
            %%%%%%
            
            app.trialnum = size(app.upindex,2);
            %             app.trialnum = trialnum(2);
            app.cue_read=1;
            app.AnalyNumEditField.Value=app.trialnum;
            %             set(handles.edit_sti1, 'String', num2str(handles.trialnum));
            msgbox(' Operation completed successfully !!!');
        end

        % Button pushed function: LoadCalButton
        function LoadCalButtonPushed(app, event)
            clc
            [filename, pathname] = uigetfile({'*.tdms';'*.mat';'*.CSV'}, 'Open calcium_data file');
            % [filename,pathname]=uigetfile({'*.tdms'},'Open calcium_data file','MultiSelect','on');
            if isequal(filename,0)
                return;
            end
            app.file_path_and_name_cal = fullfile(pathname, filename);
            [~,~,app.file_part_cal]=fileparts(app.file_path_and_name_cal);
            if strcmp(app.file_part_cal,'.csv')
                app.cal=csvread(app.file_path_and_name_cal);
            elseif strcmp(app.file_part_cal,'.tdms')
                file = TDMS_readTDMSFile(app.file_path_and_name_cal);
                app.cal=cat(1,file.data{3:end})';
            elseif strcmp(app.file_part_cal,'.mat')
                data=load(app.file_path_and_name_cal);
                if isfield(data,'data')
                    app.cal=data.data;
                else
                    msgbox('Please select the correct converted matfile !!!!!') ;
                end
                
            end
            %             handles.file_part_cal=file_part_cal;
            cal_index=zeros(size(app.cal,1),1);
            app.cal=[cal_index,app.cal];
            %     string=char(handles.file_path_and_name_cue(1));
            % file = TDMS_readTDMSFile(handles.file_path_and_name_cal);
            % cal=cat(1,file.data{3:end})';
            ncal=zeros(1,size(app.cal,2));
            ncal=[ncal;app.cal];
            
            % handles.file_path_and_name_cal = fullfile(pathname, filename);
            
            
            % file = TDMS_readTDMSFile(handles.file_path_and_name_cal);
            % cal=cat(1,file.data{3:end})';
            %             app.cal_0=ncal(2:end,:);
            app.cal=ncal(2:end,:);
            app.channelnum_cal=size(app.cal,2)-2;
            app.length_cal = size(app.cal,1);
            if isfield(app,'length_cue')
                mod_cue_cal=mod(app.length_cue,app.length_cal);
                if mod_cue_cal~=0
                    msgbox('Operation failed.Please make sure the input cue/cal is correct!!!');
                    app.cal_read=0;
                    %                     guidata(hObject, handles);
                    return
                end
            end
            %             app.rate=50;
            %             set(handles.cal_rate,'Value',2);
            % cal_number=get(handles.cal_rate,'Value');
            app.interval_cal=1/50;
            
            % handles.interval_cal=1/str2double(get(handles.edit_cal_rate,'String'));
            % handles.interval_cal=mean(cal(:,1));
            % handles.interval_cal=0.0200;
            cal_index=cell(app.channelnum_cal,1);
            for i=1:app.channelnum_cal
                cal_index{i}=num2str(i);
            end
            app.CalDropDown.Items=cal_index;
            app.CalDropDown.Value='1';
            
            %             set(handles.pm_cal,'String',num2str((1:1:handles.channelnum_cal)'));
            %             set(handles.pm_cal,'Value',1);
            app.cal_0=app.cal(:,2);
            app.cal=app.cal(:,3:end);
            %             for i=1:handles.channelnum_cal
            %                 eval(['handles.cal',num2str(i),'=','cal(:,i+2)',';']);
            %             end
            % set(handles.pushbuttonDrawAll,'Enable','on');
            % set(handles.Event_time,'Enable','on');
            % set(handles.pushbuttonAverage,'Enable','on');
            % set(handles.pushbutton_plot,'Enable','on');
            app.current_cal=app.cal(:,1);
            app.current_cal_num=1;
            app.cal_read=1;
            app.cal_editEditField.Value=app.file_path_and_name_cal ;
            %             set(handles.cal_edit, 'String', pathname);
            app.cal_raw=app.cal;
            msgbox(' Operation completed successfully !!!');
        end

        % Button pushed function: DrawallButton
        function DrawallButtonPushed(app, event)
            clc
            
            if app.cal_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end
            
            
            if app.cue_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end
            
            if mod(app.length_cue,app.length_cal)~=0
                msgbox('Operation failed.Please make sure the input cue/cal is correct!!!');
                app.cal_read=0;
                app.cue_read=0;
                return
            end
            %%
            %             app.interval_cal=app.interval_cal;
            interval=app.interval_cal/(app.length_cue/app.length_cal);
            color=['r','g','b','c','m','y','k'];
            
            figure('NumberTitle', 'off', 'Name', 'Data Preview ');
            subplot(2,1,1)
            t=interval*(1:1:app.length_cue);
            for i=1:app.channelnum_cue
                exam=app.data_cue(:,i);
                plot(t,exam+(app.channelnum_cue-i)*1.1,color(i));
                %                 eval(['plot(t,app.data_cue(:,' num2str(i) '),'+(app.channelnum_cue-i)*1.1',',''',color(i),'''',');']);
                hold on;
                title('Event Channel');
            end
            xlabel('Time (s)');
            hold off;
            
            subplot(2,1,2)
            
            %     interval_cal=handles.interval_cal;
            t=app.interval_cal*(1:1:app.length_cal);
            for i=1:app.channelnum_cal
                exam=app.cal(:,i);
                plot(t,exam,color(i));
                %                 eval(['plot(t,app.cal(:,',num2str(i),'),''',color(i),'''',');']);
                hold on;
            end
            hold off;
            xlabel('Time (s)');
            title('Cal Channel');
        end

        % Value changed function: EventTimeCheckBox
        function EventTimeCheckBoxValueChanged(app, event)
            value = app.EventTimeCheckBox.Value;
            clc
            if value==0


                value_cue = app.CueDropDown.Value;
                if app.cue_read==0
                    app.CueDropDown.Value=num2str(1);
                    return
                end
                %                 cue=eval(['handles.cue',int2str(get(hObject,'value'))]);
                cue=app.data_cue(:,str2num(value_cue));
                cue=smooth(cue,10);
                cue(cue<1)=0;
                cue(cue>=1)=1;
                app.current_cue=cue;
                app.upindex=1+find(diff(app.current_cue)==1)'-4;
                %     upindex=handles.upindex;
                app.trialnum = size(app.upindex,2);
                interval=app.interval_cal/(app.length_cue/app.length_cal);
                %     interval=1/str2double(get(handles.TB_SampleRate,'String'));
                %     trialnum = size(handles.upindex);
                if app.trialnum>1
                    for i=1:app.trialnum-1
                        for j=(i+1):app.trialnum
                            if (app.upindex(j)-app.upindex(i))*interval<1
                                app.upindex(j)=0;
                            end
                        end
                    end
                    app.upindex(app.upindex==0)=[];
                end
                
                app.trialnum = size(app.upindex);
                app.trialnum = app.trialnum(2);
                app.AnalyNumEditField.Value=app.trialnum;
                
                app.TrailFromEditField_2.Value=app.trialnum;
                app.TrailFromEditField.Value=1;


                return
            elseif value==1
                if app.cue_read==0
                    msgbox('Please make sure the cue/cal data read completed!!!!! ');
                    app.EventTimeCheckBox.Value=0;
                    return;
                    
                end
                
                event_time=str2num(app.EventtimeEditField.Value);
                if isempty(event_time)
                    msgbox('Please enter the event_time parameters after using this function !!!');
                    return
                end
                count_trialnum=length(event_time);
                
                app.trialnum=count_trialnum;
                rate=(app.length_cue/app.length_cal)/(app.interval_cal);
                event_time=round(event_time*rate);
                app.TrailFromEditField_2.Value=app.trialnum;
                app.TrailFromEditField.Value=1;
                app.AnalyNumEditField.Value=app.trialnum;
                %                 set(handles.edit_sti1, 'String', num2str(handles.trialnum));
                app.upindex=event_time;
            end
            msgbox('Event time parameter setting successful !!');
        end

        % Value changed function: RateDropDown
        function RateDropDownValueChanged(app, event)
            value = app.RateDropDown.Value;
            clc
            switch str2num(value)
                case 1
                    app.interval_cal=1/25;
                case 2
                    app.interval_cal=1/50;
                case 3
                    app.interval_cal=1/100;
            end
        end

        % Value changed function: CalDropDown
        function CalDropDownValueChanged(app, event)
            clc
            value = app.CalDropDown.Value;
            
            if app.cal_read==0
                app.CalDropDown.Value=num2str(1);
                %                     set(handles.pm_cal,'value',1);
                return
            end
            app.current_cal=app.cal(:,str2num(value));
            app.current_cal_num=str2num(value);
            
        end

        % Value changed function: CueDropDown
        function CueDropDownValueChanged(app, event)
            clc
            value = app.CueDropDown.Value;
            if app.cue_read==0
                app.CueDropDown.Value=num2str(1);
                return
            end
            %                 cue=eval(['handles.cue',int2str(get(hObject,'value'))]);
            cue=app.data_cue(:,str2num(value));
            cue=smooth(cue,10);
            cue(cue<1)=0;
            cue(cue>=1)=1;
            app.current_cue=cue;
            app.upindex=1+find(diff(app.current_cue)==1)'-4;
            %     upindex=handles.upindex;
            app.trialnum = size(app.upindex,2);
            interval=app.interval_cal/(app.length_cue/app.length_cal);
            %     interval=1/str2double(get(handles.TB_SampleRate,'String'));
            %     trialnum = size(handles.upindex);
            if app.trialnum>1
                for i=1:app.trialnum-1
                    for j=(i+1):app.trialnum
                        if (app.upindex(j)-app.upindex(i))*interval<1
                            app.upindex(j)=0;
                        end
                    end
                end
                app.upindex(app.upindex==0)=[];
            end
            
            app.trialnum = size(app.upindex);
            app.trialnum = app.trialnum(2);
            app.AnalyNumEditField.Value=app.trialnum;
            app.TrailFromEditField_2.Value=app.trialnum;
            app.TrailFromEditField.Value=1;
            %                 app.channelnum_cue=str2num(value);
            
        end

        % Value changed function: zscoreCheckBox
        function zscoreCheckBoxValueChanged(app, event)
            value = app.zscoreCheckBox.Value;
            if value==0
                app.DeltaF_FCheckBox.Value=1;
                app.zscoreCheckBox.Value=0;
            elseif value==1
                app.DeltaF_FCheckBox.Value=0;
                app.zscoreCheckBox.Value=1;
            end
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            warning('off');
            clc
            %%
            
            if app.cal_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end
            
            
            if app.cue_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end
            
            if mod(app.length_cue,app.length_cal)~=0
                msgbox('Operation failed.Please make sure the input cue/cal is correct!!!');
                app.cal_read=0;
                app.cue_read=0;
                
                return
            end
            %%
            app.length_cal;
            
            % if sti1_number==0
            %     errordlg(['The trial number is 0!',char(10),'Please try another cue channel.'],'ERROR');
            %     return;
            % end
            %% 检测trail
            
            %             sti1_number = str2num(get(handles.edit_sti1,'String'));
            %             sti1_number=app.AnalyNumEditField.Value;
            %             if sti1_number==0
            %                 errordlg(['The trial number is 0!',char(10),'Please try another cue channel.'],'ERROR');
            %                 return;
            %             end
            pre_time=app.PreTimeEditField.Value;
            %             pre_time = str2double(get(handles.edit_pre_sti_time,'String'));
            if pre_time<=0
                msgbox('Operation failed.Please make sure the pre_time parameter format is correct!!!');
                return
            end
            post_time=app.PostTimeEditField.Value;
            %             post_time = str2double(get(handles.edit_post_sti_time,'String'));
            if post_time<=0
                msgbox('Operation failed.Please make sure the post_time parameter format is correct!!!');
                return
            end
            % interval_cue = 1/str2num(get(handles.TB_SampleRate,'String'));
            interval=app.interval_cal;
            interval_cue=interval/(app.length_cue/app.length_cal);
            %             app.upindex=handles.upindex;
            upindex_C=round((app.upindex)*interval_cue/interval);
            %             trialnum=handles.trialnum;
            % upindex
            trialnumtemp = app.trialnum;
            for i=1:app.trialnum
                if upindex_C(i) < round(pre_time/interval)
                    upindex_C(i)=0;
                    trialnumtemp = trialnumtemp-1;
                end
            end
            upindex_C(upindex_C==0)=[];
            app.trialnum=trialnumtemp;
            if app.trialnum==0
                msgbox('Operation failed.Please make sure the pre_time/post_time parameter out of time!!!');
                return
            end
            for i =1:app.trialnum
                if upindex_C(i)+round(post_time/interval)>app.length_cal
                    upindex_C(i)=0;
                    trialnumtemp = trialnumtemp-1;
                end
            end
            app.trialnum=trialnumtemp;
            upindex_C(upindex_C==0)=[];
            if app.trialnum==0
                msgbox('Operation failed.Please make sure the pre_time/post_time parameter out of time!!!');
                return
            end
            app.AnalyNumEditField.Value=app.trialnum;
            %             set(handles.edit_sti1, 'String', num2str(trialnum));
            %%
            %% 读参数
            trial_from=app.TrailFromEditField.Value;
            Trail_to=app.TrailFromEditField_2.Value;
            trial_from=round(trial_from);
            Trail_to=round(Trail_to);
            
            %             trial_from = str2num(get(handles.edit_trial_from,'String'));
            if trial_from<=0
                trial_from=1;
                app.TrailFromEditField.Value=1;
            end
            if trial_from>=Trail_to
                Trail_to=app.AnalyNumEditField.Value;
                app.TrailFromEditField_2.Value=app.AnalyNumEditField.Value;
            end
            if Trail_to>app.AnalyNumEditField.Value
                Trail_to=app.AnalyNumEditField.Value;
                app.TrailFromEditField_2.Value=app.AnalyNumEditField.Value;
            end
            app.TrailFromEditField_2.Value=Trail_to;
            app.TrailFromEditField.Value=trial_from;
            
            sti1_number=Trail_to-trial_from;
            % sti1_number = str2num(get(handles.edit_sti1,'String'));
            control_time=[0,0];
            control_time(1)=app.ControlTimeEditField.Value;
            control_time(2)=app.ControlTimeEditField_2.Value;
            %             control_time_1 = str2num(get(handles.edit_control_time,'String'));
            %             control_time_2=
            %             if isempty(control_time)
            %                 control_time=[-2,0];
            %                 set(handles.edit_control_time,'String','-2 0');
            %             end
            %             if length(control_time)==1
            %                 control_time=[control_time,control_time+2];
            %                 set(handles.edit_control_time,'String',num2str(control_time));
            %             elseif length(control_time)>=2
            %                 control_time=control_time(1:2);
            if control_time(1)==control_time(2)
                control_time(1)=-pre_time;
                control_time(2)=0;
                app.ControlTimeEditField.Value=control_time(1);
                app.ControlTimeEditField_2.Value=0;
            end
            if control_time(1)>control_time(2)
                p=control_time(1);
                control_time(1)=control_time(2);
                control_time(2)=p;
            end
            app.ControlTimeEditField.Value=control_time(1);
            app.ControlTimeEditField_2.Value=control_time(2);
            %                 set(handles.edit_control_time,'String',num2str(control_time));
            %             end
            offset = str2num(app.OffsetEditField.Value);
            if length(offset)~=app.channelnum_cal
                if ~isempty(offset)
                    current_offset=offset(1);
                else
                    current_offset=0;
                end
            else
                current_offset=offset(app.current_cal_num);
            end
            %             clims = str2num(get(handles.edit_clims,'String'));
            clims(1)=app.ClimsEditField.Value;
            clims(2)=app.ClimsEditField_2.Value;
            
            if clims(1)~=clims(2)
                %                     clims=clims(1:2);
                if clims(1)>clims(2)
                    p=clims(1);
                    clims(1)=clims(2);
                    clims(2)=p;
                end
                app.ClimsEditField.Value=clims(1);
                app.ClimsEditField_2.Value=clims(2);
                
            elseif clims(1)==clims(2)
                clims=[];
                app.ClimsEditField.Value=0;
                app.ClimsEditField_2.Value=0;
                
            end
            
            z_score = app.zscoreCheckBox.Value;
            heatmap = 1;
            
            values = app.current_cal;
            % end
            trigger1_times = trigger_times_pretreatment_multi(upindex_C,trial_from,interval,sti1_number,[]);
            [psth1,psth1_mean,psth1_sem] = psth_wave2_multi(trigger1_times,interval,values,pre_time,post_time,control_time(1),control_time(2),current_offset,z_score);
            times = -pre_time:interval:post_time;
            % times=times(1:end-1);
            
            if z_score==1
                figure('NumberTitle', 'off', 'Name', 'Z_score data Preview ');
                drawErrorLine(times,psth1_mean,psth1_sem,'red',0.5);
            else
                figure('NumberTitle', 'off', 'Name', 'DeltaF/F(%) data Preview ');
                drawErrorLine(times,psth1_mean,psth1_sem,'red',0.5);
            end
            xlabel('Time (s)');
            set(gca,'Tickdir','out');
            if z_score==1
                ylabel('z-score');
            else
                ylabel('deltaF/F(%)');
            end
            
            if heatmap
                fig1 = figure('NumberTitle', 'off', 'Name', 'Heatmap Preview ');
                heatmapPlot(psth1,interval,pre_time,0.1,fig1,clims);
            end
            
            %%
            name=strrep(app.file_path_and_name_cal,app.file_part_cal,'_averagedata.mat');
            [~,name,suffix]=fileparts(name);
            name=[name suffix];
            dig=['Do you want to preserve data? If no, the data will be cached in ' name '!!!'];
            choice=questdlg(dig, ...
                'Selection Dialog', ...
                'Yes', 'No','No');
            if isempty(choice)
                if strcmp(app.file_part_cal,'.csv')
                    save(strrep(app.file_path_and_name_cal,'.csv','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                elseif strcmp(app.file_part_cal,'.tdms')
                    save(strrep(app.file_path_and_name_cal,'.tdms','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                elseif strcmp(app.file_part_cal,'.mat')
                    save(strrep(app.file_path_and_name_cal,'.mat','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                end
            else
                if strcmp(choice,'No')
                    if strcmp(app.file_part_cal,'.csv')
                        save(strrep(app.file_path_and_name_cal,'.csv','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                    elseif strcmp(app.file_part_cal,'.tdms')
                        save(strrep(app.file_path_and_name_cal,'.tdms','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                    elseif strcmp(app.file_part_cal,'.mat')
                        save(strrep(app.file_path_and_name_cal,'.mat','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                    end
                elseif strcmp(choice,'Yes')
                    [path,~,~]=fileparts(app.file_path_and_name_cal);
                    [SaveFileName,SavePathName,SaveIndex] = uiputfile('*.mat;','Save as',path); %设置默认路径
                    if isequal(SaveFileName,0) || isequal(SavePathName,0) || isequal(SaveIndex,0)
                        disp('User seleceted Cancel');
                        if strcmp(app.file_part_cal,'.csv')
                            save(strrep(app.file_path_and_name_cal,'.csv','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                        elseif strcmp(app.file_part_cal,'.tdms')
                            save(strrep(app.file_path_and_name_cal,'.tdms','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                        elseif strcmp(app.file_part_cal,'.mat')
                            save(strrep(app.file_path_and_name_cal,'.mat','_averagedata.mat'),'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                        end
                    else
                        path=[SavePathName SaveFileName];
                        save(path,'times','psth1','psth1_mean','psth1_sem','control_time','pre_time','post_time','offset');
                        %这里保存所有读入的视频的处理结果
                    end
                end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            clc
            if app.cal_read==0
                
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
                
            end
            %             if isempty(app.wave)
            %
            %                 msgbox('Please make sure the cue/cal data read completed!!!!!');
            %                 return
            %
            %             end
            %% parameter read
            offset=str2num(app.OffsetEditField.Value);
            if length(offset)~=app.channelnum_cal
                if ~isempty(offset)
                    offset=offset(1);
                else
                    offset=0;
                end
            else
                offset=offset(app.current_cal_num);
            end
            z_score=app.zscoreCheckBox.Value;
            controltime(1)=app.ControlTimeEditField_3.Value;
            controltime(2)=app.ControlTimeEditField_4.Value;
            evaluationtime(1)=app.EvaluationtimeEditField.Value;
            evaluationtime(2)=app.EvaluationtimeEditField_2.Value;
            evaluationtime(evaluationtime(:)<=0)=1;
            max_time=floor((app.interval_cal)*app.length_cal);
            evaluationtime(evaluationtime(:)>=max_time)=max_time;
            
            if evaluationtime(1)>evaluationtime(2)
                p= evaluationtime(1);
                evaluationtime(1)=evaluationtime(2);
                evaluationtime(2)=p;
                app.EvaluationtimeEditField.Value=evaluationtime(1);
                app.EvaluationtimeEditField_2.Value=evaluationtime(2);
            end
            if evaluationtime(1)==evaluationtime(2)
                evaluationtime=[];
                app.EvaluationtimeEditField.Value=0;
                app.EvaluationtimeEditField_2.Value=0;
                msgbox(' Evaluationtime Parameter setting error,calculate the entire duration!!');
                %                 return
            end
            
            controltime(controltime(:)>=max_time)=max_time;
            controltime(controltime(:)<=0)=1;
            if controltime(1)==controltime(2)
                msgbox('Plese input the start and end time of Control time/s parameter!!');
                return
            end
            if controltime(1)>controltime(2)
                p= controltime(1);
                controltime(1)=controltime(2);
                controltime(2)=p;
                app.ControlTimeEditField_3.Value=controltime(1);
                app.ControlTimeEditField_4.Value=controltime(2);
            end
            
            app.interval_cal;
            
            values = app.current_cal;
            %% 计算
            
            controldata=values(round(controltime(1)/app.interval_cal):round(controltime(2)/app.interval_cal));
            if isempty(evaluationtime)
                evaluationdata=values;
                evaluationtime(1)=0;
                evaluationtime(2)=app.interval_cal*length(evaluationdata);
            else
                evaluationdata=values(round(evaluationtime(1)/app.interval_cal):round(evaluationtime(2)/app.interval_cal));
            end
            %% plot
            %             figure;
            
            if z_score==1
                figure('NumberTitle', 'off', 'Name', 'z_score data Preview ');
                plot(app.interval_cal*(1:1:length(evaluationdata)),(evaluationdata-mean(controldata))/std(controldata));
                xlabel('Time (s)');
                ylabel('z-score');
                data=(evaluationdata-mean(controldata))/std(controldata);
            else
                figure('NumberTitle', 'off', 'Name', 'deltaF/F(%) data Preview ');
                plot(app.interval_cal*(1:1:length(evaluationdata)),(evaluationdata-mean(controldata))/(mean(controldata)-offset)*100);
                xlabel('Time (s)');
                ylabel('deltaF/F(%)');
                data=(evaluationdata-mean(controldata))/(mean(controldata)-offset)*100;
            end
            data=data;
            times=app.interval_cal*(1:1:length(evaluationdata));
            %% save data
            name=strrep(app.file_path_and_name_cal,app.file_part_cal,'_plotdata.mat');
            [~,name,suffix]=fileparts(name);
            name=[name suffix];
            dig=['Do you want to preserve data? If no, the data will be cached in ' name '!!!'];
            choice=questdlg(dig, ...
                'Selection Dialog', ...
                'Yes', 'No','No');
            if isempty(choice)
                if strcmp(app.file_part_cal,'.csv')
                    save(strrep(app.file_path_and_name_cal,'.csv','_plotdata.mat'),'times','data','controltime','evaluationtime');
                elseif strcmp(app.file_part_cal,'.tdms')
                    save(strrep(app.file_path_and_name_cal,'.tdms','_plotdata.mat'),'times','data','controltime','evaluationtime');
                elseif strcmp(app.file_part_cal,'.mat')
                    save(strrep(app.file_path_and_name_cal,'.mat','_plotdata.mat'),'times','data','controltime','evaluationtime');
                end
            else
                if strcmp(choice, 'No')
                    if strcmp(app.file_part_cal,'.csv')
                        save(strrep(app.file_path_and_name_cal,'.csv','_plotdata.mat'),'times','data','controltime','evaluationtime');
                    elseif strcmp(app.file_part_cal,'.tdms')
                        save(strrep(app.file_path_and_name_cal,'.tdms','_plotdata.mat'),'times','data','controltime','evaluationtime');
                    elseif strcmp(app.file_part_cal,'.mat')
                        save(strrep(app.file_path_and_name_cal,'.mat','_plotdata.mat'),'times','data','controltime','evaluationtime');
                    end
                elseif strcmp(choice, 'Yes')
                    [path,~,~]=fileparts(app.file_path_and_name_cal);
                    [SaveFileName,SavePathName,SaveIndex] = uiputfile('*.mat;','Save as',path); %设置默认路径
                    if isequal(SaveFileName,0) || isequal(SavePathName,0) || isequal(SaveIndex,0)
                        disp('User seleceted Cancel');
                        if strcmp(app.file_part_cal,'.csv')
                            save(strrep(app.file_path_and_name_cal,'.csv','_plotdata.mat'),'times','data','controltime','evaluationtime');
                        elseif strcmp(app.file_part_cal,'.tdms')
                            save(strrep(app.file_path_and_name_cal,'.tdms','_plotdata.mat'),'times','data','controltime','evaluationtime');
                        elseif strcmp(app.file_part_cal,'.mat')
                            save(strrep(app.file_path_and_name_cal,'.mat','_plotdata.mat'),'times','data','controltime','evaluationtime');
                        end
                    else
                        path=[SavePathName SaveFileName];
                        save(path,'times','data','controltime','evaluationtime');
                        %这里保存所有读入的视频的处理结果
                    end
                end
            end
        end

        % Button pushed function: DrawCalButton_2
        function DrawCalButton_2Pushed(app, event)
            clc
            warning('off');
            %%
            
            if app.cal_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end
            
            wave=app.current_cal';
            FPS_start_time=app.StartTimeEditField.Value;
            FPS_end_time  =app.EndTimeEditField.Value;
            Baseline_start_time=app.BaselineStartTimeEditField.Value;
            Baseline_end_time  =app.BaselineEndTimeEditField.Value;
            
            interval=app.interval_cal;
            FPS_start=FPS_start_time/interval;
            FPS_end  =FPS_end_time/interval;
            if FPS_start>FPS_end
                p=FPS_end;
                FPS_end=FPS_start;
                FPS_start=p;
                app.StartTimeEditField.Value=FPS_start*interval;
                app.EndTimeEditField.Value=FPS_end*interval;
            end
            if FPS_start<=1
                FPS_start=1;
                app.StartTimeEditField.Value=FPS_start*interval;
            end
            if (FPS_end-FPS_start)<(1/interval)
                
                msgbox('The end time minus the start time is too short to calculate!!!');
                wave=app.current_cal';
                intervel=app.interval_cal;
                time=1:1:length(wave);
                time=time*intervel;
                figure('NumberTitle', 'off', 'Name', 'Cal Current Channel Preview ');
                plot(time,wave);
                return
                
            end
            
            if FPS_end>length(wave)
                FPS_end=length(wave);
                app.EndTimeEditField.Value=FPS_end*interval;
            end
            
            
            starttime=FPS_start*interval;
            endtime=FPS_end*interval;
            
            Baseline_start=Baseline_start_time/interval;
            Baseline_end  =Baseline_end_time/interval;
            
            if Baseline_start>Baseline_end
                p=Baseline_end;
                Baseline_end=Baseline_start;
                Baseline_start=p;
                app.BaselineStartTimeEditField.Value=Baseline_start*interval;
                app.BaselineEndTimeEditField.Value=Baseline_end*interval;
            end
            if Baseline_start<=1
                Baseline_start=1;
                app.BaselineStartTimeEditField.Value=Baseline_start*interval;
            end
            if Baseline_end>length(wave)
                Baseline_end=length(wave);
                app.BaselineEndTimeEditField.Value=Baseline_end*interval;
            end
            
            Baseline_start_time=Baseline_start*interval;
            Baseline_end_time=Baseline_end*interval;


            %             app.current_cal_num=str2num(app.CalDropDown.Value);
            %             app.current_cal=eval(['handles.cal',int2str(current_cal_num)]);
            %             wave=app.current_cal';
            %             intervel=app.interval_cal;
            time=1:1:length(wave);
            time=time*interval;
            figure('NumberTitle', 'off', 'Name', 'Cal Current Channel Preview ');
            plot(time,wave);
            hold on
            line([starttime starttime],[min(wave) max(wave)],'Color','r','LineWidth',1.5,'LineStyle','--');
            hold on
            line([endtime endtime],[min(wave) max(wave)],'Color','r','LineWidth',1.5,'LineStyle','--');
            
            hold on
            line([starttime endtime],[min(wave) min(wave)],'Color','r','LineWidth',1.5,'LineStyle','--');
            hold on
            line([starttime endtime],[max(wave) max(wave)],'Color','r','LineWidth',1.5,'LineStyle','--');
            hold on
            
            line([Baseline_start_time Baseline_start_time],[min(wave) max(wave)],'Color','g','LineWidth',1.5,'LineStyle',':');
            hold on
            line([Baseline_end_time Baseline_end_time],[min(wave) max(wave)],'Color','g','LineWidth',1.5,'LineStyle',':');
            
            hold on
            line([Baseline_start_time Baseline_end_time],[min(wave) min(wave)],'Color','g','LineWidth',1.5,'LineStyle',':');
            hold on
            line([Baseline_start_time Baseline_end_time],[max(wave) max(wave)],'Color','g','LineWidth',1.5,'LineStyle',':');
            hold off
        end

        % Button pushed function: EventFPSButton
        function EventFPSButtonPushed(app, event)
            clc;
            warning('off');
            %%
            if app.cal_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end
            
            %             app.current_cal_num=get(handles.pm_cal,'value');
            %             app.current_cal=eval(['handles.cal',int2str(current_cal_num)]);
            wave=app.current_cal;
            wave_label=1:1:length(wave);
            
            FPS_start_time=app.StartTimeEditField.Value;
            FPS_end_time  =app.EndTimeEditField.Value;
            Baseline_start_time=app.BaselineStartTimeEditField.Value;
            Baseline_end_time  =app.BaselineEndTimeEditField.Value;
            
            interval=app.interval_cal;
            Multiple=app.MultipleEditField.Value;
            FPS_start=FPS_start_time/interval;
            FPS_end  =FPS_end_time/interval;
            if FPS_start>FPS_end
                p=FPS_end;
                FPS_end=FPS_start;
                FPS_start=p;
                app.StartTimeEditField.Value=FPS_start*interval;
                app.EndTimeEditField.Value=FPS_end*interval;
            end
            if FPS_start<=1
                FPS_start=1;
                app.StartTimeEditField.Value=FPS_start*interval;
            end
            if (FPS_end-FPS_start)<(1/interval)
                
                msgbox('The end time minus the start time is too short to calculate!!!');
                return
                
            end
            
            if FPS_end>length(wave)
                FPS_end=length(wave);
                app.EndTimeEditField.Value=FPS_end*interval;
            end
            
            
            starttime=FPS_start*interval;
            endtime=FPS_end*interval;
            
            Baseline_start=Baseline_start_time/interval;
            Baseline_end  =Baseline_end_time/interval;
            
            if Baseline_start>Baseline_end
                p=Baseline_end;
                Baseline_end=Baseline_start;
                Baseline_start=p;
                app.BaselineStartTimeEditField.Value=Baseline_start*interval;
                app.BaselineEndTimeEditField.Value=Baseline_end*interval;
            end
            if Baseline_start<=1
                Baseline_start=1;
                app.BaselineStartTimeEditField.Value=Baseline_start*interval;
            end
            if Baseline_end>length(wave)
                Baseline_end=length(wave);
                app.BaselineEndTimeEditField.Value=Baseline_end*interval;
            end
            
            Baseline_start_time=Baseline_start*interval;
            Baseline_end_time=Baseline_end*interval;


            wave_FPS_label=wave_label>=FPS_start & wave_label<=FPS_end;
            wave_Base_label=wave_label>=Baseline_start & wave_label<=Baseline_end;
            wave_FPS =wave(wave_FPS_label);
            data_line_2=zeros(length(wave_FPS),1);
            wave_Base=wave(wave_Base_label);
            Mean_base=mean(wave_Base(:));
            data_line_index=find(wave_FPS(:)>Multiple*Mean_base);
            data_line_index_1=data_line_index(1:end-1);
            data_line_index_2=data_line_index(2:end);
            data_line_index_3=data_line_index_2-data_line_index_1;
            data_line_index_4=find(data_line_index_3(:)==1);
            data_line_index=[data_line_index data_line_index];
            for i=1:length(data_line_index_4)
                %     data_line_index(data_line_index_4(i):data_line_index_4(i)+1)=1;
                data_line_index(data_line_index_4(i):data_line_index_4(i)+1,2)=1;
            end
            data_line_index_n=data_line_index(:,2)==1;
            data_line_index_n=data_line_index(data_line_index_n,1);
            duration=round(app.DurationEditField.Value/interval);
            if isempty(data_line_index_n)
                data_line_index_n=data_line_index_n;
                figure('NumberTitle', 'off', 'Name', 'Event Preview ');
                time_fps=1:1:length(wave_FPS);
                time_fps=time_fps*interval;
                plot(time_fps,wave_FPS);
                Event_num=0;
            else
                data_line_index_n_2=data_line_index_n(2:end)-data_line_index_n(1:end-1);
                data_line_index_n_2_index=find(data_line_index_n_2~=1);
                if isempty(data_line_index_n_2_index)
                    if length(data_line_index_n)<duration
                        data_line_index_n=[];
                        figure('NumberTitle', 'off', 'Name', 'Event Preview ');
                        time_fps=1:1:length(wave_FPS);
                        time_fps=time_fps*interval;
                        plot(time_fps,wave_FPS);
                        Event_num=0;
                    else
                        figure('NumberTitle', 'off', 'Name', 'Event Preview ');
                        time_fps=1:1:length(wave_FPS);
                        time_fps=time_fps*interval;
                        plot(time_fps,wave_FPS);
                        hold on
                        exam=data_line_index_n;
                        examt=exam*interval;
                        fpsmax=ones(length(exam),1)*max(wave_FPS(:));
                        fpsmax(1)=min(wave_FPS(:));
                        fpsmax(end)=min(wave_FPS(:));
                        fpsmin=ones(length(exam),1)*min(wave_FPS(:));
                        plot(examt,fpsmax,'Color','r','LineWidth',1);
                        hold on
                        plot(examt,fpsmin,'Color','r','LineWidth',1);
                        Event_num=1;
                    end
                else
                    data_line_index_n_3=cell(1,length(data_line_index_n_2_index)+1);
                    data_line_index_n_3_size=zeros(1,length(data_line_index_n_2_index)+1);
                    for i=1:length(data_line_index_n_2_index)+1
                        if i==1
                            data_line_index_n_3{i}=data_line_index_n(1:data_line_index_n_2_index(1));
                            data_line_index_n_3_size(i)=length(data_line_index_n_3{i});
                        elseif i==length(data_line_index_n_2_index)+1
                            data_line_index_n_3{i}=data_line_index_n(data_line_index_n_2_index(end)+1:end);
                            data_line_index_n_3_size(i)=length(data_line_index_n_3{i});
                        else
                            data_line_index_n_3{i}=data_line_index_n(data_line_index_n_2_index(i-1)+1:data_line_index_n_2_index(i));
                            data_line_index_n_3_size(i)=length(data_line_index_n_3{i});
                        end
                    end
                    data_line_index_n_3_size_index=find(data_line_index_n_3_size<duration);
                    if ~isempty(data_line_index_n_3_size_index)
                        for i=1:length(data_line_index_n_3_size_index)
                            data_line_index_n_3{data_line_index_n_3_size_index(i)}=[];
                        end
                    end
                    data_line_index_n_3(cellfun(@isempty,data_line_index_n_3))=[];
                    
                    %                     data_line_index_n=[];
                    %                     for i=1:length(data_line_index_n_3)
                    %                         data_line_index_n=[data_line_index_n;data_line_index_n_3{i}];
                    %                     end
                    figure('NumberTitle', 'off', 'Name', 'Event Preview ');
                    time_fps=1:1:length(wave_FPS);
                    time_fps=time_fps*interval;
                    plot(time_fps,wave_FPS);
                    hold on
                    for i=1:length(data_line_index_n_3)
                        exam=data_line_index_n_3{i};
                        examt=exam*interval;
                        fpsmax=ones(length(exam),1)*max(wave_FPS(:));
                        fpsmax(1)=min(wave_FPS(:));
                        fpsmax(end)=min(wave_FPS(:));
                        fpsmin=ones(length(exam),1)*min(wave_FPS(:));
                        plot(examt,fpsmax,'Color','r','LineWidth',1);
                        hold on
                        plot(examt,fpsmin,'Color','r','LineWidth',1)
                    end
                    hold off
                    Event_num=length(data_line_index_n_3);
                end
                
            end
            %%
            
            
            %%
            %             data_line_2(data_line_index_n)=1;
            %             data_line_2=smooth(data_line_2,20);
            %             data_line_2(data_line_2(:)>=0.4)=1;
            %             data_line_2(data_line_2(:)<0.4)=0;
            %             Event=data_line_2(1:end-1)-data_line_2(2:end);
            %             Event_num=length(find(Event==-1));
            %             figure('NumberTitle', 'off', 'Name', 'Event Preview ');
            %             time_fps=1:1:length(wave_FPS);
            %             time_fps=time_fps*interval;
            %             plot(time_fps,wave_FPS);
            %             hold on
            %             exam_fps_max=data_line_2*max(wave_FPS(:));
            %
            %             exma_fps_concert=((data_line_2(2:end)-data_line_2(1:end-1))==1|(data_line_2(2:end)-data_line_2(1:end-1))==-1);
            %             exam_fps_max(exma_fps_concert)=min(wave_FPS(:));
            %             time_fps_max=find(exam_fps_max~=0);
            %             plot(time_fps(time_fps_max),exam_fps_max(time_fps_max),'r');
            %             hold on
            %             exam_fps_min=data_line_2*min(wave_FPS(:));
            %             time_fps_min=find(exam_fps_min~=0);
            %             plot(time_fps(time_fps_min),exam_fps_min(time_fps_min),'r');
            %             hold off;
            Event_fps=Event_num/((FPS_end-FPS_start)*interval);
            event_cell=cell(1,1);
            event_cell{1}=num2str(Event_fps);
            Name=cell(1,1);
            Name{1}='Event FPS';
            %     data_line(:,j)=data_line_2;
            %             file_part_cal=handles.file_part_cal;
            EvaluationStartTime=starttime;
            EvaluationEndTime=endtime;
            ControlStartTime=Baseline_start_time;
            ControlEndTime=Baseline_end_time;
            
            name=strrep(app.file_path_and_name_cal,app.file_part_cal,'_Event_FPS.mat');
            [~,name,suffix]=fileparts(name);
            name=[name suffix];
            dig=['Do you want to preserve data? If no, the data will be cached in ' name '!!!'];
            choice=questdlg(dig, ...
                'Selection Dialog', ...
                'Yes', 'No','No');
            if isempty(choice)
                %
                save(strrep(app.file_path_and_name_cal,app.file_part_cal,'_Event_FPS.mat'),'Event_fps','EvaluationStartTime','EvaluationEndTime','ControlStartTime','ControlEndTime','Multiple');
            else
                if strcmp(choice,'No')
                    save(strrep(app.file_path_and_name_cal,app.file_part_cal,'_Event_FPS.mat'),'Event_fps','EvaluationStartTime','EvaluationEndTime','ControlStartTime','ControlEndTime','Multiple');
                elseif strcmp(choice,'Yes')
                    [path,~,~]=fileparts(app.file_path_and_name_cal);
                    [SaveFileName,SavePathName,SaveIndex] = uiputfile('*.mat;','Save as',path); %设置默认路径
                    if isequal(SaveFileName,0) || isequal(SavePathName,0) || isequal(SaveIndex,0)
                        disp('User seleceted Cancel');
                        save(strrep(app.file_path_and_name_cal,app.file_part_cal,'_Event_FPS.mat'),'Event_fps','EvaluationStartTime','EvaluationEndTime','ControlStartTime','ControlEndTime','Multiple');
                    else
                        path=[SavePathName SaveFileName];
                        save(path,'Event_fps','EvaluationStartTime','EvaluationEndTime','ControlStartTime','ControlEndTime','Multiple');
                        %这里保存所有读入的视频的处理结果
                    end
                end
            end
            
            %             if strcmp(app.file_part_cal,'.csv')
            % %                 xlsx_name=strrep(app.file_path_and_name_cal,'.csv','_Event_FPS.xlsx');
            % %                 xlswrite(xlsx_name,[Name; event_cell]);
            %                 mat_name=strrep(app.file_path_and_name_cal,'.csv','_Event_FPS.mat');
            %                 save(mat_name,'Event_fps','starttime','endtime','Baseline_start_time','Baseline_end_time','Multiple');
            % %                 xlswrite(xlsx_name,[Name; event_cell]);
            %                 msgbox('Calculate completed!!!')
            %             elseif strcmp(app.file_part_cal,'.tdms')
            % %                 xlsx_name=strrep(app.file_path_and_name_cal,'.tdms','_Event_FPS.xlsx');
            % %                 xlswrite(xlsx_name,[Name; event_cell]);
            %                 mat_name=strrep(app.file_path_and_name_cal,'.tdms','_Event_FPS.mat');
            %                 save(mat_name,'Event_fps','starttime','endtime','Baseline_start_time','Baseline_end_time','Multiple');
            %                 %                 xlswrite(xlsx_name,[Name; event_cell]);
            %                 msgbox('Calculate completed!!!')
            %             elseif strcmp(app.file_part_cal,'.mat')
            % %                 xlsx_name=strrep(app.file_path_and_name_cal,'.mat','_Event_FPS.xlsx');
            % %                 xlswrite(xlsx_name,[Name; event_cell]);
            %                 mat_name=strrep(app.file_path_and_name_cal,'.mat','_Event_FPS.mat');
            %                 save(mat_name,'Event_fps','starttime','endtime','Baseline_start_time','Baseline_end_time','Multiple');
            %                 %                 xlswrite(xlsx_name,[Name; event_cell]);
            %                 msgbox('Calculate completed!!!')
            %             end
        end

        % Button pushed function: LoadTrailButton
        function LoadTrailButtonPushed(app, event)
            clc
            [filename, pathname] = uigetfile({'*.mat'}, 'Open trail_data file');
            if isequal(filename,0)
                app.trail_data=[];
                return;
            end
            app.file_path_and_name_trail = fullfile(pathname, filename);
            [~,~,file_part_trail]=fileparts(app.file_path_and_name_trail);
            if strcmp(file_part_trail,'.mat')
                
                app.trail_data=load(app.file_path_and_name_trail);
                if ~isfield(app.trail_data,'psth1_mean')
                    msgbox('Failed!Please make sure the trail data is correct !!!!');
                    app.trail_data=[];
                    return
                end
                app.AUC_editEditField.Value=app.file_path_and_name_trail;
                %                 set(handles.AUC_edit,'String',handles.file_path_and_name_trail);
                msgbox('Data read completed !!!!!!!!!!!')
                %                 app.trail_data=trail_data;
                %                 handles.trail_data_read=1;
            else
                msgbox('Failed!Please make sure the trail data is correct !!!!');
                return;
            end
        end

        % Button pushed function: DrawTrailButton
        function DrawTrailButtonPushed(app, event)
            clc
            if isempty(app.trail_data)
                
                msgbox('Please make sure the trail data read completed!!!!!');
                return
                
            end
            figure('NumberTitle', 'off', 'Name', 'Trail Data Preview ');
            
            time_upper=max(app.trail_data.times(:));
            app.AUCEndTimeEditField.Value=time_upper;
            
            %             set(handles.AUC_e_time,'String',num2str(max(trail_data.times(:))));
            app.PeakEndTimeEditField.Value=time_upper;
            %             set(handles.Peak_e_time,'String',num2str(time_upper));
            drawErrorLine(app.trail_data.times,app.trail_data.psth1_mean,app.trail_data.psth1_sem,'Red',0.5);
            
            figure('NumberTitle', 'off', 'Name', 'Heatmap Preview ');
            %                 heatmapPlot(data.psth1,interval,pre_time,0.1,fig1,clims);
            imagesc(app.trail_data.times,1:1:size(app.trail_data.psth1,1),app.trail_data.psth1);
        end

        % Button pushed function: AUCButton
        function AUCButtonPushed(app, event)
            clc
            if isempty(app.trail_data)
                
                msgbox('Please make sure the trail data read completed!!!!!');
                return
                
            end
            %% read data
            app.trail_data;
            start_time= app.AUCStartTimeEditField.Value;
            end_time  = app.AUCEndTimeEditField.Value;
            
            psth1=app.trail_data.psth1;
            psth1_mean=app.trail_data.psth1_mean;
            psth1_sem=app.trail_data.psth1_sem;
            times=app.trail_data.times;
            %%
            if start_time>end_time
                p=end_time;
                end_time=start_time;
                start_time=p;
                app.AUCStartTimeEditField.Value=start_time;
                app.AUCEndTimeEditField.Value=end_time;
            elseif start_time==end_time
                msgbox('Parameter setting error!!!');
                return
            end
            
            if start_time<=min(times(:))
                start_time=min(times(:));
                app.AUCStartTimeEditField.Value=start_time;
            end
            if end_time>=max(times(:))
                end_time=max(times(:));
                app.AUCEndTimeEditField.Value=end_time;
                %                 set(handles.AUC_e_time,'String',num2str(end_time));
            end
            
            time_rang=times>= start_time & times<=end_time;
            AUC=zeros(1,size(psth1,1)+1);
            for i=1:size(psth1,1)
                psth_current=psth1(i,:);
                psth_auc=psth_current(time_rang);
                AUC(i)=(sum(psth_auc))*(times(2)-times(1));
            end
            psth_current=psth1_mean;
            psth_auc=psth_current(time_rang);
            AUC(size(psth1,1)+1)=(sum(psth_auc))*(times(2)-times(1));
            figure('NumberTitle', 'off', 'Name', 'AUC Data Preview ');
            Bar=cell(1,size(psth1,1)+1);
            for i=1:size(psth1,1)+1
                if i~=size(psth1,1)+1
                    Bar{i}=['Trail' num2str(i) ' AUC'];
                else
                    Bar{i}='Trail Mean AUC';
                end
            end
            subplot(1,2,1)
            bar(categorical(Bar),AUC);
            %     AUC_str=num2str(AUC);
            AUC_cell=cell(1,length(AUC));
            for i=1:length(AUC)
                AUC_cell{i}=num2str(AUC(i));
            end
            %     bar(AUC);
            subplot(1,2,2)
            drawErrorLine(times,psth1_mean,psth1_sem,'Blue',0.5);
            hold on
            ylim=get(gca,'Ylim');
            %     time_r=times(time_rang);
            %     time_1=
            label=ones(1,size(psth_auc,2))*ylim(2);
            label(1)=ylim(1);
            label(end)=ylim(1);
            plot(times(time_rang),label,'r--');
            hold on
            label=ones(1,size(psth_auc,2))*ylim(1);
            plot(times(time_rang),label,'r--');
            ylabel('deltaF/F(%)');
            xlabel('Time (s)');
            %             xlsx_name=strrep(app.file_path_and_name_trail,'.mat','_AUC.xlsx');
            %             xlswrite(xlsx_name,[Bar; AUC_cell]);
            mat_name=strrep(app.file_path_and_name_trail,'.mat','_AUC.mat');
            
            AUC_trail=cell(2,size(psth1,1)+1);
            for i=1:size(psth1,1)+1
                
                AUC_trail{1,i}=Bar{1,i};
                AUC_trail{2,i}=AUC_cell{1,i};
            end
            AUC_start_time=start_time;
            AUC_end_time=end_time;
            save(mat_name,'AUC_trail','AUC_start_time','AUC_end_time')
            %             xlswrite(xlsx_name,[Bar; AUC_cell]);
            msgbox('Calculate completed!!!')
        end

        % Button pushed function: PeakValleyButton
        function PeakValleyButtonPushed(app, event)
            clc
            if isempty(app.trail_data)
                
                msgbox('Please make sure the trail data read completed!!!!!');
                return
                
            end
            app.trail_data;
            psth1=app.trail_data.psth1;
            psth1_mean=app.trail_data.psth1_mean;
            psth1_sem=app.trail_data.psth1_sem;
            times=app.trail_data.times;
            floor_time=app.PeakStartTimeEditField.Value;
            upper_time=app.PeakEndTimeEditField.Value;
            if upper_time<floor_time
                p=upper_time;
                upper_time=floor_time;
                floor_time=p;
                app.PeakStartTimeEditField.Value=floor_time;
                app.PeakEndTimeEditField.Value=upper_time;
                
            elseif floor_time==upper_time
                msgbox('Parameter setting error!!!');
                return
            end
            if upper_time>=max(times(:))
                upper_time=max(times(:));
                app.PeakEndTimeEditField.Value=upper_time;
            end
            if floor_time<=min(times(:))
                floor_time=min(times(:));
                app.PeakStartTimeEditField.Value=floor_time;
            end
            peak_time=times>=floor_time&times<=upper_time;
            peak_range=psth1_mean(peak_time);
            peak_times=times(peak_time);
            Bar=cell(1,4);
            Bar{1}='Peak Value';
            Bar{2}='Peak Time';
            Bar{3}='Valley Value';
            Bar{4}='Valley Time';
            Max=max(peak_range(:));
            Min=min(peak_range(:));
            Max_label=find(peak_range(:)==Max);
            Min_label=find(peak_range(:)==Min);
            Max_time=peak_times(Max_label(1));
            Min_time=peak_times(Min_label(1));
            Peak=cell(1,4);
            Peak{1}=num2str(Max);
            Peak{2}=num2str(Max_time);
            Peak{3}=num2str(Min);
            Peak{4}=num2str(Min_time);
            %             xlsx_name=strrep(app.file_path_and_name_trail,'.mat','_Peak.xlsx');
            %             xlswrite(xlsx_name,[Bar; Peak]);
            MAT_name=strrep(app.file_path_and_name_trail,'.mat','_Peak_Valley.mat');
            Peak_Valley=cell(2,4);
            for i=1:4
                Peak_Valley{1,i}=Bar{1,i};
                Peak_Valley{2,i}=Peak{1,i};
            end
            stattime=app.PeakStartTimeEditField.Value;
            endtime=app.PeakEndTimeEditField.Value;
            save(MAT_name,'Peak_Valley','stattime','endtime');
            %             xlswrite(xlsx_name,[Bar; Peak]);
            msgbox('Calculate finished !!!!');
        end

        % Button pushed function: LoadTrailsButton
        function LoadTrailsButtonPushed(app, event)
            
            [FileName_all,FilePath]=uigetfile({'*.mat'},'mat文件读取','MultiSelect','on');%读取多个mat文件
            if isnumeric(FileName_all)
                return;
            end
            if ischar(FileName_all)
                N=1;
                
                string=FileName_all;
                astring=[FilePath,string];
                example=load(astring);
                if ~isfield(example,'psth1_mean')
                    msgbox('Please load the corret delatf/f data!!!!');
                    clc
                    app.psth1=[];
                    app.psth_sem_all=[];
                    app.psth_ALL=[];
                    app.psth_mean=[];
                    app.psth_times=[];
                    app.psth_sem=[];
                    %                     handles.average_mice_read=0;
                    return
                end
                app.psth_times=example.times;
                app.psth_ALL=example.psth1;
                app.psth_mean=example.psth1_mean;
                app.psth_sem=example.psth1_sem;
                app.psth_sem_all=example.psth1_sem;
                app.psth1=example.psth1;
                app.EditField.Value=FilePath;
                %                 set(handles.edit22, 'String', FilePath);
            else
                N=size(FileName_all);
                N=N(2);
                
                string=char(FileName_all(1));
                astring=[FilePath,string];
                example=load(astring);
                if ~isfield(example,'psth1_mean')
                    msgbox('Please load the corret delatf/f data!!!!');
                    clc
                    app.psth1=[];
                    app.psth_sem_all=[];
                    app.psth_ALL=[];
                    app.psth_mean=[];
                    app.psth_times=[];
                    app.psth_sem=[];
                    %                     handles.average_mice_read=0;
                    return
                end
                clc
                app.psth1=[];
                app.psth_sem_all=[];
                app.psth_ALL=[];
                app.psth_mean=[];
                app.psth_times=[];
                app.psth_sem=[];
                
                app.psth_times=example.times;
                Long=size(example.psth1_mean);
                Long=Long(2);
                Data=zeros(N,Long);
                app.psth_sem_all=zeros(N,Long);
                for i=1:N
                    string=char(FileName_all(i));
                    astring=[FilePath,string];
                    example=load(astring);
                    %         if isfield
                    %         end
                    if ~isfield(example,'psth1_mean')
                        msgbox('Please load the corret delatf/f data!!!!');
                        app.psth_sem_all=[];
                        app.psth_ALL=[];
                        app.psth_mean=[];
                        app.psth_times=[];
                        app.psth_sem=[];
                        app.psth1=[];
                        %                         handles.average_mice_read=0;
                        return
                    end
                    if length(example.psth1_mean)~=Long
                        errordlg('Please select the files with the same data size','Dimension error');
                        app.psth_sem_all=[];
                        app.psth_ALL=[];
                        app.psth_mean=[];
                        app.psth_times=[];
                        app.psth_sem=[];
                        app.psth1=[];
                        return;
                    end
                    app.psth1=[app.psth1;example.psth1];
                    
                    Data(i,1:Long)=example.psth1_mean;
                    app.psth_sem_all(i,1:Long)=example.psth1_sem;
                end
                app.psth_ALL=Data;
                Data_mean=sum(Data)/N;
                app.psth_mean=Data_mean;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%求取psth_sem变量%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.psth_sem = std(app.psth_ALL)/(N-1)^0.5;
                app.EditField.Value=FilePath;
            end
            
            
            app.file_N=N;
            msgbox('Loading operation is completed!!! ')
        end

        % Button pushed function: AverageButton_2
        function AverageButton_2Pushed(app, event)
            clc
            if isempty(app.psth_sem_all)
                msgbox('Please make sure trails loading operation are successful!!!!');
                return
            end
            if isempty(app.psth_ALL)
                msgbox('Please make sure trails loading operation are successful!!!!');
                return
            end
            if isempty(app.psth_mean)
                msgbox('Please make sure trails loading operation are successful!!!!');
                return
            end
            if isempty(app.psth_sem)
                msgbox('Please make sure trails loading operation are successful!!!!');
                return
            end
            if isempty(app.psth_times)
                msgbox('Please make sure trails loading operation are successful!!!!');
                return
            end
            %             psth_ALL=handles.psth_ALL;
            %             psth_mean=handles.psth_mean;
            %             psth_times=handles.psth_times;
            %             psth_sem=handles.psth_sem;
            %%
            figure('NumberTitle', 'off', 'Name', 'Average/mice delatf/f data Preview ');
            drawErrorLine(app.psth_times,app.psth_mean,app.psth_sem,'red',0.5);
            xlabel('Time (s)');
            figure('NumberTitle', 'off', 'Name', 'Average/mice Meantrail heatmap Preview ');
            imagesc(app.psth_times,1:1:size(app.psth_ALL,1),app.psth_ALL);
            figure('NumberTitle', 'off', 'Name', 'Average/mice Alltrail heatmap Preview ');
            imagesc(app.psth_times,1:1:size(app.psth1,1),app.psth1);
            % xlabel('Time (s)');
            % figure('NumberTitle', 'off', 'Name', 'Average/mice heatmap Preview ');
            % imagesc(psth_ALL,[min(min(psth_ALL)),max(max(psth_ALL))]);
            xlabel('Time (s)');
            rgb1=str2num(app.RGB1EditField.Value);
            rgb2=str2num(app.RGB2EditField.Value);
            rgb3=str2num(app.RGB3EditField.Value);
            rgb4=str2num(app.RGB4EditField.Value);
            rgb5=str2num(app.RGB5EditField.Value);
            rgb6=str2num(app.RGB6EditField.Value);
            if length(rgb1)~=3||length(rgb2)~=3||length(rgb3)~=3||length(rgb4)~=3||length(rgb5)~=3||length(rgb6)~=3
                msgbox('Please make sure the RGB parameter setting is correct!!!');
                return
            end
            %             file_N;
            %             psth_ALL;
            %             psth_mean=handles.psth_mean;
            %             psth_times=handles.psth_times;
            %             psth_sem=handles.psth_sem;
            %             psth_sem_all=handles.psth_sem_all;
            if app.file_N<=6
                figure('NumberTitle', 'off', 'Name', 'Data combine show sem Preview ');
                for i=1:app.file_N
                    eval(['color=rgb' num2str(i) '/255;']);
                    data=app.psth_ALL(i,:);
                    sem=app.psth_sem_all(i,:);
                    drawErrorLine(app.psth_times,data,sem,color,0.5);
                    hold on;
                end
                xlabel('Time (s)');
                set(gca,'Tickdir','out');
                ylabel('DeltaF/F(%)');
                hold off;
                figure('NumberTitle', 'off', 'Name', 'Data combine non sem Preview ');
                for i=1:app.file_N
                    eval(['color=rgb' num2str(i) '/255;']);
                    data=app.psth_ALL(i,:);
                    sem=app.psth_sem_all(i,:);
                    %         drawErrorLine(psth_times,data,sem,color,0.5);
                    plot(app.psth_times,data,'color',color,'LineWidth',2);
                    hold on;
                end
                xlabel('Time (s)');
                set(gca,'Tickdir','out');
                ylabel('DeltaF/F(%)');
                hold off;
            elseif app.file_N>6
                figure('NumberTitle', 'off', 'Name', 'Data combine show sem Preview ');
                for i=1:app.file_N
                    num=mod(i,6);
                    if num==0
                        num=6;
                    end
                    eval(['color=rgb' num2str(num) '/255;']);
                    data=app.psth_ALL(i,:);
                    sem=app.psth_sem_all(i,:);
                    drawErrorLine(app.psth_times,data,sem,color,0.5);
                    hold on;
                end
                xlabel('Time (s)');
                set(gca,'Tickdir','out');
                ylabel('DeltaF/F(%)');
                hold off;
                
                figure('NumberTitle', 'off', 'Name', 'Data combine non sem Preview ');
                for i=1:app.file_N
                    num=mod(i,6);
                    if num==0
                        num=6;
                    end
                    eval(['color=rgb' num2str(num) '/255;']);
                    data=app.psth_ALL(i,:);
                    sem=app.psth_sem_all(i,:);
                    plot(app.psth_times,data,'color',color,'LineWidth',2);
                    hold on;
                end
                xlabel('Time (s)');
                set(gca,'Tickdir','out');
                ylabel('DeltaF/F(%)');
                hold off;
            end
            %             path=[app.FilePath,'AverageMeanData.mat'];
            psth1_mean=app.psth_mean;
            psth1=app.psth_ALL;
            times=app.psth_mean;
            psth1_sem=app.psth_sem;
            
            name=[app.FilePath,'AverageMeanData.mat'];
            [~,name,suffix]=fileparts(name);
            name=[name suffix];
            dig=['Do you want to preserve data? If no, the data will be cached in ' name '!!!'];
            choice=questdlg(dig, ...
                'Selection Dialog', ...
                'Yes', 'No','No');
            if isempty(choice)
                %
                save([app.FilePath,'AverageMeanData.mat'],'psth1_mean','psth1','times','psth1_sem');
            else
                if strcmp(choice,'No')
                    save([app.FilePath,'AverageMeanData.mat'],'psth1_mean','psth1','times','psth1_sem');
                elseif strcmp(choice,'Yes')
                    
                    
                    [SaveFileName,SavePathName,SaveIndex] = uiputfile('*.mat;','Save as',app.FilePath); %设置默认路径
                    if isequal(SaveFileName,0) || isequal(SavePathName,0) || isequal(SaveIndex,0)
                        disp('User seleceted Cancel');
                        save([app.FilePath,'AverageMeanData.mat'],'psth1_mean','psth1','times','psth1_sem');
                    else
                        path=[SavePathName SaveFileName];
                        save(path,'psth1_mean','psth1','times','psth1_sem');
                        %这里保存所有读入的视频的处理结果
                    end
                end
            end
            %             handles.path=path;
            % set(handles.pushbutton6,'Enable','on');
            
            %             save(path,'psth1_mean','psth1','times','psth1_sem');
        end

        % Value changed function: LambdaDropDown
        function LambdaDropDownValueChanged(app, event)
            
            value = app.LambdaDropDown.Value;
            clc
            correction_time=app.CorrecttimesEditField.Value;
            correction_time_2=app.CorrecttimesEditField_2.Value;
            % interval=handles.interval;
            if app.cal_read==0
                return
            end
            interval=app.interval_cal;
            
            if isempty(correction_time)
                correction_time=0;
            end
            if isempty(correction_time_2)
                correction_time_2=interval*length(app.current_cal);
            end
            
            if correction_time>correction_time_2
                p=correction_time;
                correction_time=correction_time_2;
                correction_time_2=p;
            end
            
            if correction_time<=0
                correction_time=0;
            end


            if correction_time_2>interval*length(app.current_cal)||correction_time_2==correction_time
                msgbox('Please make sure if the correction_time is out of time range!!!');
                return
            end
            app.CorrecttimesEditField.Value=correction_time;
            app.CorrecttimesEditField_2.Value=correction_time_2;
            
            %%
            %             if isempty(correction_time)||correction_time==0
            %                 correction_data_n=app.current_cal;
            %                 correction_data_n=correction_data_n';
            %                 part_index=0;
            %             else
            ss=round(correction_time/interval);
            ee=round(correction_time_2/interval);
            if ss==0
                ss=1;
                
            end
            correction_data_n=app.current_cal(ss:ee);
            correction_data_n=correction_data_n';
            if round(correction_time/interval)==0
                part_index=0;
                part_index_end=ee+1;
            else
                part_index=round(correction_time/interval)-1;
                part_index_end=ee+1;
            end
            %             end
            %%
            raw_s=correction_data_n(1);
            raw_e=correction_data_n(end);
            switch str2num(value)
                case 5
                    lambda=10e5;
                    [correction_data_n,~]=airPLS(correction_data_n, lambda,2,0.05);
                    correction_data_n=correction_data_n+(raw_s-correction_data_n(1));
                    correction_data_n=correction_data_n';
                    if part_index==0
                        correction_data_n=[correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        %                         t=
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    else
                        correction_data_n=[app.current_cal(1:part_index);correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    end
                    
                case 6
                    lambda=10e6;
                    [correction_data_n,~]=airPLS(correction_data_n, lambda,2,0.05);
                    correction_data_n=correction_data_n+(raw_s-correction_data_n(1));
                    correction_data_n=correction_data_n';
                    if part_index==0
                        correction_data_n=[correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    else
                        correction_data_n=[app.current_cal(1:part_index);correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    end
                    
                case 7
                    lambda=10e7;
                    [correction_data_n,~]=airPLS(correction_data_n, lambda,2,0.05);
                    correction_data_n=correction_data_n+(raw_s-correction_data_n(1));
                    correction_data_n=correction_data_n';
                    if part_index==0
                        correction_data_n=[correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    else
                        correction_data_n=[app.current_cal(1:part_index);correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    end
                    
                case 8
                    lambda=10e8;
                    [correction_data_n,~]=airPLS(correction_data_n, lambda,2,0.05);
                    correction_data_n=correction_data_n+(raw_s-correction_data_n(1));
                    correction_data_n=correction_data_n';
                    if part_index==0
                        correction_data_n=[correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    else
                        correction_data_n=[app.current_cal(1:part_index);correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    end
                    
                case 9
                    lambda=10e9;
                    [correction_data_n,~]=airPLS(correction_data_n, lambda,2,0.05);
                    correction_data_n=correction_data_n+(raw_s-correction_data_n(1));
                    correction_data_n=correction_data_n';
                    if part_index==0
                        correction_data_n=[correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    else
                        correction_data_n=[app.current_cal(1:part_index);correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    end
                    
                case 10
                    lambda=10e10;
                    [correction_data_n,~]=airPLS(correction_data_n, lambda,2,0.05);
                    correction_data_n=correction_data_n+(raw_s-correction_data_n(1));
                    correction_data_n=correction_data_n';
                    if part_index==0
                        correction_data_n=[correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    else
                        correction_data_n=[app.current_cal(1:part_index);correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    end
                    
                case 11
                    lambda=10e11;
                    [correction_data_n,~]=airPLS(correction_data_n, lambda,2,0.05);
                    correction_data_n=correction_data_n+(raw_s-correction_data_n(1));
                    correction_data_n=correction_data_n';
                    if part_index==0
                        correction_data_n=[correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    else
                        correction_data_n=[app.current_cal(1:part_index);correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    end
                    
                case 12
                    lambda=10e12;
                    [correction_data_n,~]=airPLS(correction_data_n, lambda,2,0.05);
                    correction_data_n=correction_data_n+(raw_s-correction_data_n(1));
                    correction_data_n=correction_data_n';
                    if part_index==0
                        correction_data_n=[correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    else
                        correction_data_n=[app.current_cal(1:part_index);correction_data_n;app.current_cal(part_index_end:end)-raw_e+correction_data_n(end)];
                        figure('NumberTitle', 'off', 'Name', 'Exp_fit data Preview ');
                        plot((1:1:length(correction_data_n))*interval,correction_data_n,'r');
                        hold on
                        plot((1:1:length(correction_data_n))*interval,app.current_cal,'b');
                        legend('CorrectionData','RawData');
                    end
                    
            end
            app.correction_data=correction_data_n;
            app.index_corr=app.current_cal_num;
        end

        % Value changed function: DeltaF_FCheckBox
        function DeltaF_FCheckBoxValueChanged(app, event)
            value = app.DeltaF_FCheckBox.Value;
            clc
            if value==0
                app.DeltaF_FCheckBox.Value=0;
                app.zscoreCheckBox.Value=1;
            elseif value==1
                app.DeltaF_FCheckBox.Value=1;
                app.zscoreCheckBox.Value=0;
            end
            
        end

        % Button pushed function: DrawCalButton
        function DrawCalButtonPushed(app, event)
            clc
            
            if app.cal_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end


            correction_time=app.CorrecttimesEditField.Value;
            correction_time_2=app.CorrecttimesEditField_2.Value;
            % interval=handles.interval;
            if app.cal_read==0
                return
            end
            interval=app.interval_cal;
            
            if isempty(correction_time)
                correction_time=0;
            end
            if isempty(correction_time_2)
                correction_time_2=interval*length(app.current_cal);
            end
            
            if correction_time>correction_time_2
                p=correction_time;
                correction_time=correction_time_2;
                correction_time_2=p;
            end
            
            if correction_time<=0
                correction_time=0;
            end


            if correction_time_2>interval*length(app.current_cal)||correction_time_2==correction_time
                msgbox('Please make sure if the correction_time is out of time range!!!');
                app.current_cal=app.cal(:,app.current_cal_num);
                %             current_cal=eval(['handles.cal',int2str(current_cal_num)]);
                wave=app.current_cal';
                intervel=app.interval_cal;
                time=1:1:length(wave);
                time=time*intervel;
                figure('NumberTitle', 'off', 'Name', 'Cal Current Channel Preview ');
                plot(time,wave);
                return
            end
            app.CorrecttimesEditField.Value=correction_time;
            app.CorrecttimesEditField_2.Value=correction_time_2;
            
            
            %             current_cal_num=get(handles.pm_cal,'value');
            app.current_cal=app.cal(:,app.current_cal_num);
            %             current_cal=eval(['handles.cal',int2str(current_cal_num)]);
            wave=app.current_cal';
            intervel=app.interval_cal;
            time=1:1:length(wave);
            time=time*intervel;
            figure('NumberTitle', 'off', 'Name', 'Cal Current Channel Preview ');
            plot(time,wave);
            hold on
            line([correction_time correction_time],[min(wave) max(wave)],'Color','r','LineWidth',1.5,'LineStyle','--');
            hold on
            line([correction_time_2 correction_time_2],[min(wave) max(wave)],'Color','r','LineWidth',1.5,'LineStyle','--');
            
            hold on
            line([correction_time correction_time_2],[min(wave) min(wave)],'Color','r','LineWidth',1.5,'LineStyle','--');
            hold on
            line([correction_time correction_time_2],[max(wave) max(wave)],'Color','r','LineWidth',1.5,'LineStyle','--');
            hold off
        end

        % Button pushed function: ReplaceButton
        function ReplaceButtonPushed(app, event)
            clc
            if app.cal_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return;
            end
            
            
            if app.cue_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return;
            end
            
            if mod(app.length_cue,app.length_cal)~=0
                msgbox('Operation failed.Please make sure the input cue/cal is correct!!!');
                app.cal_read=0;
                app.cue_read=0;
                return;
            end
            if isempty(app.correction_data)
                msgbox('Please select the lambda parameter first');
                return
            end
            %             correction_data=handles.correction_data;
            
            dig=['Please make sure whether to replace the ' num2str(app.index_corr) ' channel datadata  with the correction data'];
            choice=questdlg(dig, ...
                'Selection Dialog', ...
                'Yes', 'No','No');
            if isempty(choice)
                msgbox('User cancel operation!!');
            else
                if strcmp(choice,'No')
                    msgbox('User cancel operation!!');
                elseif strcmp(choice,'Yes')
                    app.cal(:,app.index_corr)=app.correction_data;
                    app.CalDropDown.Value=num2str(app.index_corr);
                    
                    app.current_cal=app.correction_data;
                    
                    correcttime(1)=app.CorrecttimesEditField.Value;
                    correcttime(2)=app.CorrecttimesEditField_2.Value;
                    data=[app.cal_0,app.cal];
                    filepath=strrep(app.file_path_and_name_cal,app.file_part_cal,'_Cal_Correction.mat');
                    lambda=str2num(app.LambdaDropDown.Value);
                    save(filepath,'data','lambda','','correcttime');
                    msgbox('Operation succeeds !!!!');
                end
                
            end
        end

        % Button pushed function: TomatcsvButton
        function TomatcsvButtonPushed(app, event)
            clc
            if app.cal_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end
            
            
            if app.cue_read==0
                msgbox('Please make sure the cue/cal data read completed!!!!!');
                return
            end
            
            if mod(app.length_cue,app.length_cal)~=0
                msgbox('Operation failed.Please make sure the input cue/cal is correct!!!');
                app.cal_read=0;
                app.cue_read=0;
                return
            end
            %%
            %             app.interval_cal=app.interval_cal;
            %             interval=app.interval_cal/(app.length_cue/app.length_cal);
            h=waitbar(0,'please wait');
            filepath=strrep(app.file_path_and_name_cue,app.file_part_cue,'.csv');
            waitbar(10/100,h);
            data=app.data_cue;
            csvwrite(filepath,data);
            waitbar(25/100,h);
            filepath=strrep(app.file_path_and_name_cue,app.file_part_cue,'.mat');
            save(filepath,'data');
            waitbar(50/100,h);
            filepath=strrep(app.file_path_and_name_cal,app.file_part_cal,'.csv');
            data=[app.cal_0,app.cal_raw];
            csvwrite(filepath,data);
            waitbar(75/100,h);
            filepath=strrep(app.file_path_and_name_cal,app.file_part_cal,'.mat');
            save(filepath,'data');
            waitbar(100/100,h);
            delete(h);
            msgbox('Operation succeeds !!!!');
            
            %             color=['r','g','b','c','m','y','k'];
            %
            %             figure('NumberTitle', 'off', 'Name', 'Event Channel Preview ');
            %             t=interval*(1:1:app.length_cue);
            %             for i=1:app.channelnum_cue
            %                 exam=app.data_cue(:,i);
            %                 plot(t,exam+(app.channelnum_cue-i)*1.1,color(i));
            %                 %                 eval(['plot(t,app.data_cue(:,' num2str(i) '),'+(app.channelnum_cue-i)*1.1',',''',color(i),'''',');']);
            %                 hold on;
            %             end
            %             xlabel('Time (s)');
            %             hold off;
            %
            %
            %             figure('NumberTitle', 'off', 'Name', 'Cal Channel Preview ');
            %             %     interval_cal=handles.interval_cal;
            %             t=app.interval_cal*(1:1:app.length_cal);
            %             for i=1:app.channelnum_cal
            %                 exam=app.cal(:,i);
            %                 plot(t,exam,color(i));
            %                 %                 eval(['plot(t,app.cal(:,',num2str(i),'),''',color(i),'''',');']);
            %                 hold on;
            %             end
            %             hold off;
            %             xlabel('Time (s)');
        end

        % Callback function
        function SaveButtonPushed(app, event)
            
        end

        % Button pushed function: PlayBackButton
        function PlayBackButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.mat'}, 'Open trail_data file');
            if isequal(filename,0)
                
                return;
            end
            filename = fullfile(pathname, filename);
            [~,~,file_part_trail]=fileparts(filename);
            if strcmp(file_part_trail,'.mat')
                
                data=load(filename);
                if ~isfield(data,'psth1_mean')
                    msgbox('Failed!Please make sure the trail data is correct !!!!');
                    
                    return
                end
                %                 figure('NumberTitle', 'off', 'Name', 'Z_score data Preview ');
                %                 drawErrorLine(times,psth1_mean,psth1_sem,'red',0.5);
                %
                figure('NumberTitle', 'off', 'Name', 'Trail data Preview ');
                drawErrorLine(data.times,data.psth1_mean,data.psth1_sem,'red',0.5);
                
                %                 xlabel('Time (s)');
                %                 set(gca,'Tickdir','out');
                %                 if z_score==1
                %                     ylabel('z-score');
                %                 else
                ylabel('deltaF/F(%)');
                %                 end
                
                
                figure('NumberTitle', 'off', 'Name', 'Heatmap Preview ');
                %                 heatmapPlot(data.psth1,interval,pre_time,0.1,fig1,clims);
                imagesc(data.times,1:1:size(data.psth1,1),data.psth1);
                
                %                 app.trail_data=trail_data;
                %                 handles.trail_data_read=1;
            else
                msgbox('Failed!Please make sure the trail data is correct !!!!');
                return;
            end
        end

        % Button pushed function: PlayBackButton_2
        function PlayBackButton_2Pushed(app, event)
            [filename, pathname] = uigetfile({'*.mat'}, 'Open trail_data file');
            if isequal(filename,0)
                
                return;
            end
            filename = fullfile(pathname, filename);
            [~,~,file_part_trail]=fileparts(filename);
            if strcmp(file_part_trail,'.mat')
                
                data=load(filename);
                if ~isfield(data,'data')
                    msgbox('Failed!Please make sure the trail data is correct !!!!');
                    
                    return
                end
                %                 figure('NumberTitle', 'off', 'Name', 'Z_score data Preview ');
                %                 drawErrorLine(times,psth1_mean,psth1_sem,'red',0.5);
                %
                figure('NumberTitle', 'off', 'Name', 'Trail data Preview ');
                plot(data.times,data.data);
                
                ylabel('deltaF/F(%)');
                
            else
                msgbox('Failed!Please make sure the data is correct !!!!');
                return;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultiPhotometryUIFigure = uifigure;
            app.MultiPhotometryUIFigure.Name = 'MultiPhotometry';
            app.TabGroup = uitabgroup(app.MultiPhotometryUIFigure);
            app.DeltaFFZscoreCalculationTab = uitab(app.TabGroup);
            app.DeltaFFZscoreCalculationTab.Title = 'DeltaF/F Zscore Calculation';
            app.cue_editEditFieldLabel = uilabel(app.DeltaFFZscoreCalculationTab);
            app.cue_editEditFieldLabel.Text = 'cue_edit';
            app.cue_editEditField = uieditfield(app.DeltaFFZscoreCalculationTab, 'text');
            app.LoadCueButton = uibutton(app.DeltaFFZscoreCalculationTab, 'push');
            app.LoadCueButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCueButtonPushed, true);
            app.LoadCueButton.Text = 'Load Cue';
            app.cal_editEditFieldLabel = uilabel(app.DeltaFFZscoreCalculationTab);
            app.cal_editEditFieldLabel.Text = 'cal_edit';
            app.cal_editEditField = uieditfield(app.DeltaFFZscoreCalculationTab, 'text');
            app.LoadCalButton = uibutton(app.DeltaFFZscoreCalculationTab, 'push');
            app.LoadCalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalButtonPushed, true);
            app.LoadCalButton.Text = 'Load Cal';
            app.DrawallButton = uibutton(app.DeltaFFZscoreCalculationTab, 'push');
            app.DrawallButton.ButtonPushedFcn = createCallbackFcn(app, @DrawallButtonPushed, true);
            app.DrawallButton.Text = 'Draw all';
            app.ParameterSetPanel = uipanel(app.DeltaFFZscoreCalculationTab);
            app.ParameterSetPanel.Title = 'Parameter Set';
            app.RateDropDownLabel = uilabel(app.ParameterSetPanel);
            app.RateDropDownLabel.Text = 'Rate';
            app.RateDropDown = uidropdown(app.ParameterSetPanel);
            app.RateDropDown.Items = {'25', '50', '100'};
            app.RateDropDown.ValueChangedFcn = createCallbackFcn(app, @RateDropDownValueChanged, true);
            app.RateDropDown.Value = '50';
            app.CueDropDownLabel = uilabel(app.ParameterSetPanel);
            app.CueDropDownLabel.Text = 'Cue';
            app.CueDropDown = uidropdown(app.ParameterSetPanel);
            app.CueDropDown.Items = {'1', '2', '3', '4'};
            app.CueDropDown.ValueChangedFcn = createCallbackFcn(app, @CueDropDownValueChanged, true);
            app.CueDropDown.Value = '1';
            app.EventtimeEditFieldLabel = uilabel(app.ParameterSetPanel);
            app.EventtimeEditFieldLabel.Text = 'Event time';
            app.EventtimeEditField = uieditfield(app.ParameterSetPanel, 'text');
            app.EventtimeEditField.Value = '0 2 3';
            app.EventTimeCheckBox = uicheckbox(app.ParameterSetPanel);
            app.EventTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EventTimeCheckBoxValueChanged, true);
            app.EventTimeCheckBox.Text = 'Event Time';
            app.CalDropDownLabel = uilabel(app.ParameterSetPanel);
            app.CalDropDownLabel.Text = 'Cal';
            app.CalDropDown = uidropdown(app.ParameterSetPanel);
            app.CalDropDown.Items = {'1', '2', '3', '4'};
            app.CalDropDown.ValueChangedFcn = createCallbackFcn(app, @CalDropDownValueChanged, true);
            app.CalDropDown.Value = '1';
            app.OffsetEditFieldLabel = uilabel(app.ParameterSetPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.ParameterSetPanel, 'text');
            app.CalCorrectionPanel = uipanel(app.DeltaFFZscoreCalculationTab);
            app.CalCorrectionPanel.Title = 'Cal Correction';
            app.LambdaDropDownLabel = uilabel(app.CalCorrectionPanel);
            app.LambdaDropDownLabel.Text = 'Lambda';
            app.LambdaDropDown = uidropdown(app.CalCorrectionPanel);
            app.LambdaDropDown.Items = {'5', '6', '7', '8', '9', '10', '11', '12'};
            app.LambdaDropDown.ValueChangedFcn = createCallbackFcn(app, @LambdaDropDownValueChanged, true);
            app.LambdaDropDown.Value = '7';
            app.DrawCalButton = uibutton(app.CalCorrectionPanel, 'push');
            app.DrawCalButton.ButtonPushedFcn = createCallbackFcn(app, @DrawCalButtonPushed, true);
            app.DrawCalButton.Text = 'DrawCal';
            app.ReplaceButton = uibutton(app.CalCorrectionPanel, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.ReplaceButton.Text = 'Replace';
            app.CorrecttimesEditField_3Label = uilabel(app.CalCorrectionPanel);
            app.CorrecttimesEditField_3Label.Text = 'Correct time/s';
            app.CorrecttimesEditField = uieditfield(app.CalCorrectionPanel, 'numeric');
            app.CorrecttimesEditField.Value = 5;
            app.CorrecttimesEditField_4Label = uilabel(app.CalCorrectionPanel);
            app.CorrecttimesEditField_4Label.Text = 'Correct time/s';
            app.CorrecttimesEditField_2 = uieditfield(app.CalCorrectionPanel, 'numeric');
            app.CorrecttimesEditField_2.Value = 100;
            app.AveragePanel = uipanel(app.DeltaFFZscoreCalculationTab);
            app.AveragePanel.Title = 'Average';
            app.AnalyNumEditFieldLabel = uilabel(app.AveragePanel);
            app.AnalyNumEditFieldLabel.Text = 'AnalyNum';
            app.AnalyNumEditField = uieditfield(app.AveragePanel, 'numeric');
            app.TrailFromEditFieldLabel = uilabel(app.AveragePanel);
            app.TrailFromEditFieldLabel.Text = 'Trail From';
            app.TrailFromEditField = uieditfield(app.AveragePanel, 'numeric');
            app.PreTimeEditFieldLabel = uilabel(app.AveragePanel);
            app.PreTimeEditFieldLabel.Text = 'Pre Time';
            app.PreTimeEditField = uieditfield(app.AveragePanel, 'numeric');
            app.PreTimeEditField.Value = 2;
            app.PostTimeEditFieldLabel = uilabel(app.AveragePanel);
            app.PostTimeEditFieldLabel.Text = 'Post Time';
            app.PostTimeEditField = uieditfield(app.AveragePanel, 'numeric');
            app.PostTimeEditField.Value = 10;
            app.ControlTimeEditFieldLabel = uilabel(app.AveragePanel);
            app.ControlTimeEditFieldLabel.Text = 'Control Time';
            app.ControlTimeEditField = uieditfield(app.AveragePanel, 'numeric');
            app.ControlTimeEditField.Value = -2;
            app.ControlTimeEditField_2Label = uilabel(app.AveragePanel);
            app.ControlTimeEditField_2Label.Text = 'Control Time';
            app.ControlTimeEditField_2 = uieditfield(app.AveragePanel, 'numeric');
            app.ClimsEditFieldLabel = uilabel(app.AveragePanel);
            app.ClimsEditFieldLabel.Text = 'Clims';
            app.ClimsEditField = uieditfield(app.AveragePanel, 'numeric');
            app.ClimsEditField_2Label = uilabel(app.AveragePanel);
            app.ClimsEditField_2Label.Text = 'Clims';
            app.ClimsEditField_2 = uieditfield(app.AveragePanel, 'numeric');
            app.AverageButton = uibutton(app.AveragePanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.zscoreCheckBox = uicheckbox(app.AveragePanel);
            app.zscoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @zscoreCheckBoxValueChanged, true);
            app.zscoreCheckBox.Text = 'z-score';
            app.DeltaF_FCheckBox = uicheckbox(app.AveragePanel);
            app.DeltaF_FCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeltaF_FCheckBoxValueChanged, true);
            app.DeltaF_FCheckBox.Text = 'DeltaF_F';
            app.DeltaF_FCheckBox.Value = true;
            app.PlayBackButton = uibutton(app.AveragePanel, 'push');
            app.PlayBackButton.ButtonPushedFcn = createCallbackFcn(app, @PlayBackButtonPushed, true);
            app.PlayBackButton.Text = 'PlayBack';
            app.TrailFromEditField_2 = uieditfield(app.AveragePanel, 'numeric');
            app.PlotPanel = uipanel(app.DeltaFFZscoreCalculationTab);
            app.PlotPanel.Title = 'Plot';
            app.ControlTimeEditField_3Label = uilabel(app.PlotPanel);
            app.ControlTimeEditField_3Label.Text = 'Control Time';
            app.ControlTimeEditField_3 = uieditfield(app.PlotPanel, 'numeric');
            app.ControlTimeEditField_3.Value = 10;
            app.ControlTimeEditField_4Label = uilabel(app.PlotPanel);
            app.ControlTimeEditField_4Label.Text = 'Control Time';
            app.ControlTimeEditField_4 = uieditfield(app.PlotPanel, 'numeric');
            app.ControlTimeEditField_4.Value = 20;
            app.EvaluationtimeEditFieldLabel = uilabel(app.PlotPanel);
            app.EvaluationtimeEditFieldLabel.Text = 'Evaluation time';
            app.EvaluationtimeEditField = uieditfield(app.PlotPanel, 'numeric');
            app.EvaluationtimeEditField_2Label = uilabel(app.PlotPanel);
            app.EvaluationtimeEditField_2Label.Text = 'Evaluation time';
            app.EvaluationtimeEditField_2 = uieditfield(app.PlotPanel, 'numeric');
            app.PlotButton = uibutton(app.PlotPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlayBackButton_2 = uibutton(app.PlotPanel, 'push');
            app.PlayBackButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayBackButton_2Pushed, true);
            app.PlayBackButton_2.Text = 'PlayBack';
            app.TomatcsvButton = uibutton(app.DeltaFFZscoreCalculationTab, 'push');
            app.TomatcsvButton.ButtonPushedFcn = createCallbackFcn(app, @TomatcsvButtonPushed, true);
            app.TomatcsvButton.Text = 'To mat/csv';
            app.DeltaffTreatTab = uitab(app.TabGroup);
            app.DeltaffTreatTab.Title = 'Deltaf/f Treat';
            app.EventFrequencyPanel = uipanel(app.DeltaffTreatTab);
            app.EventFrequencyPanel.Title = 'Event Frequency';
            app.StartTimeEditFieldLabel = uilabel(app.EventFrequencyPanel);
            app.StartTimeEditFieldLabel.Text = 'StartTime';
            app.StartTimeEditField = uieditfield(app.EventFrequencyPanel, 'numeric');
            app.EndTimeEditFieldLabel = uilabel(app.EventFrequencyPanel);
            app.EndTimeEditFieldLabel.Text = 'EndTime';
            app.EndTimeEditField = uieditfield(app.EventFrequencyPanel, 'numeric');
            app.EndTimeEditField.Value = 2;
            app.BaselineStartTimeEditFieldLabel = uilabel(app.EventFrequencyPanel);
            app.BaselineStartTimeEditFieldLabel.Text = 'BaselineStartTime';
            app.BaselineStartTimeEditField = uieditfield(app.EventFrequencyPanel, 'numeric');
            app.BaselineEndTimeEditFieldLabel = uilabel(app.EventFrequencyPanel);
            app.BaselineEndTimeEditFieldLabel.Text = 'BaselineEndTime';
            app.BaselineEndTimeEditField = uieditfield(app.EventFrequencyPanel, 'numeric');
            app.BaselineEndTimeEditField.Value = 2;
            app.MultipleEditFieldLabel = uilabel(app.EventFrequencyPanel);
            app.MultipleEditFieldLabel.Text = 'Multiple';
            app.MultipleEditField = uieditfield(app.EventFrequencyPanel, 'numeric');
            app.MultipleEditField.Value = 2.91;
            app.DrawCalButton_2 = uibutton(app.EventFrequencyPanel, 'push');
            app.DrawCalButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawCalButton_2Pushed, true);
            app.DrawCalButton_2.Text = 'DrawCal';
            app.EventFPSButton = uibutton(app.EventFrequencyPanel, 'push');
            app.EventFPSButton.ButtonPushedFcn = createCallbackFcn(app, @EventFPSButtonPushed, true);
            app.EventFPSButton.Text = 'EventFPS';
            app.EvaluationStartsLabel = uilabel(app.EventFrequencyPanel);
            app.EvaluationStartsLabel.Text = 'Evaluation Start/s';
            app.EvaluationEndsLabel = uilabel(app.EventFrequencyPanel);
            app.EvaluationEndsLabel.Text = 'Evaluation End/s';
            app.ControlStarttimesLabel = uilabel(app.EventFrequencyPanel);
            app.ControlStarttimesLabel.Text = 'Control Start time/s';
            app.ControlEndtimesLabel = uilabel(app.EventFrequencyPanel);
            app.ControlEndtimesLabel.Text = 'Control End time/s';
            app.DurationEditFieldLabel = uilabel(app.EventFrequencyPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.EventFrequencyPanel, 'numeric');
            app.DurationEditField.Value = 0.1;
            app.AUCPeakValleyPanel = uipanel(app.DeltaffTreatTab);
            app.AUCPeakValleyPanel.Title = 'AUC/Peak/Valley';
            app.AUC_editEditFieldLabel = uilabel(app.AUCPeakValleyPanel);
            app.AUC_editEditFieldLabel.Text = 'AUC_edit';
            app.AUC_editEditField = uieditfield(app.AUCPeakValleyPanel, 'text');
            app.LoadTrailButton = uibutton(app.AUCPeakValleyPanel, 'push');
            app.LoadTrailButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrailButtonPushed, true);
            app.LoadTrailButton.Text = 'LoadTrail';
            app.DrawTrailButton = uibutton(app.AUCPeakValleyPanel, 'push');
            app.DrawTrailButton.ButtonPushedFcn = createCallbackFcn(app, @DrawTrailButtonPushed, true);
            app.DrawTrailButton.Text = 'DrawTrail';
            app.AUCStartTimeEditFieldLabel = uilabel(app.AUCPeakValleyPanel);
            app.AUCStartTimeEditFieldLabel.Text = 'AUCStartTime';
            app.AUCStartTimeEditField = uieditfield(app.AUCPeakValleyPanel, 'numeric');
            app.PeakStartTimeEditFieldLabel = uilabel(app.AUCPeakValleyPanel);
            app.PeakStartTimeEditFieldLabel.Text = 'PeakStartTime';
            app.PeakStartTimeEditField = uieditfield(app.AUCPeakValleyPanel, 'numeric');
            app.AUCEndTimeEditFieldLabel = uilabel(app.AUCPeakValleyPanel);
            app.AUCEndTimeEditFieldLabel.Text = 'AUCEndTime';
            app.AUCEndTimeEditField = uieditfield(app.AUCPeakValleyPanel, 'numeric');
            app.AUCEndTimeEditField.Value = 5;
            app.PeakEndTimeEditFieldLabel = uilabel(app.AUCPeakValleyPanel);
            app.PeakEndTimeEditFieldLabel.Text = 'PeakEndTime';
            app.PeakEndTimeEditField = uieditfield(app.AUCPeakValleyPanel, 'numeric');
            app.PeakEndTimeEditField.Value = 5;
            app.AUCButton = uibutton(app.AUCPeakValleyPanel, 'push');
            app.AUCButton.ButtonPushedFcn = createCallbackFcn(app, @AUCButtonPushed, true);
            app.AUCButton.Text = 'AUC';
            app.PeakValleyButton = uibutton(app.AUCPeakValleyPanel, 'push');
            app.PeakValleyButton.ButtonPushedFcn = createCallbackFcn(app, @PeakValleyButtonPushed, true);
            app.PeakValleyButton.Text = 'Peak/Valley';
            app.AveragemicePanel = uipanel(app.DeltaffTreatTab);
            app.AveragemicePanel.Title = 'Average mice';
            app.EditFieldLabel = uilabel(app.AveragemicePanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.AveragemicePanel, 'text');
            app.LoadTrailsButton = uibutton(app.AveragemicePanel, 'push');
            app.LoadTrailsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrailsButtonPushed, true);
            app.LoadTrailsButton.Text = 'Load Trails';
            app.AverageButton_2 = uibutton(app.AveragemicePanel, 'push');
            app.AverageButton_2.ButtonPushedFcn = createCallbackFcn(app, @AverageButton_2Pushed, true);
            app.AverageButton_2.Text = 'Average';
            app.RGB1EditFieldLabel = uilabel(app.AveragemicePanel);
            app.RGB1EditFieldLabel.Text = 'RGB1';
            app.RGB1EditField = uieditfield(app.AveragemicePanel, 'text');
            app.RGB1EditField.Value = '255 0 0';
            app.RGB2EditFieldLabel = uilabel(app.AveragemicePanel);
            app.RGB2EditFieldLabel.Text = 'RGB2';
            app.RGB2EditField = uieditfield(app.AveragemicePanel, 'text');
            app.RGB2EditField.Value = '0 0 205';
            app.RGB3EditFieldLabel = uilabel(app.AveragemicePanel);
            app.RGB3EditFieldLabel.Text = 'RGB3';
            app.RGB3EditField = uieditfield(app.AveragemicePanel, 'text');
            app.RGB3EditField.Value = '255 215 0';
            app.RGB4EditFieldLabel = uilabel(app.AveragemicePanel);
            app.RGB4EditFieldLabel.Text = 'RGB4';
            app.RGB4EditField = uieditfield(app.AveragemicePanel, 'text');
            app.RGB4EditField.Value = '46 139 87';
            app.RGB5EditFieldLabel = uilabel(app.AveragemicePanel);
            app.RGB5EditFieldLabel.Text = 'RGB5';
            app.RGB5EditField = uieditfield(app.AveragemicePanel, 'text');
            app.RGB5EditField.Value = '139 101 8';
            app.RGB6EditFieldLabel = uilabel(app.AveragemicePanel);
            app.RGB6EditFieldLabel.Text = 'RGB6';
            app.RGB6EditField = uieditfield(app.AveragemicePanel, 'text');
            app.RGB6EditField.Value = '139 0 139';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiPhotometry

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MultiPhotometryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultiPhotometryUIFigure)
        end
    end
end